classdef holo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LaserDopplerHolographyUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainviewTab                     matlab.ui.container.Tab
        CurrentFilePanel                matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        Panel_6                         matlab.ui.container.Panel
        depthrangeEditField             matlab.ui.control.NumericEditField
        depthrangeEditFieldLabel        matlab.ui.control.Label
        depthpositionEditField          matlab.ui.control.NumericEditField
        depthpositionEditFieldLabel     matlab.ui.control.Label
        volumesizeEditField             matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        VolumeViewRenderingButton       matlab.ui.control.Button
        NotesTextArea                   matlab.ui.control.TextArea
        NotesTextAreaLabel              matlab.ui.control.Label
        GridLayout3                     matlab.ui.container.GridLayout
        ImagerenderingPanel             matlab.ui.container.Panel
        gwEditField                     matlab.ui.control.NumericEditField
        gwLabel                         matlab.ui.control.Label
        ImageChoiceDropDown             matlab.ui.control.DropDown
        compositef3EditField            matlab.ui.control.NumericEditField
        compositef3EditFieldLabel       matlab.ui.control.Label
        compositef2EditField            matlab.ui.control.NumericEditField
        compositef2EditFieldLabel       matlab.ui.control.Label
        compositef1EditField            matlab.ui.control.NumericEditField
        compositef1EditFieldLabel       matlab.ui.control.Label
        HighfrequencyrangeLabel         matlab.ui.control.Label
        LowfrequencyrangeLabel          matlab.ui.control.Label
        f2EditField                     matlab.ui.control.NumericEditField
        f2EditFieldLabel                matlab.ui.control.Label
        f1EditField                     matlab.ui.control.NumericEditField
        f1EditFieldLabel                matlab.ui.control.Label
        AberrationcompensationPanel     matlab.ui.container.Panel
        PreviewLabel                    matlab.ui.control.Label
        masknumiterEditField            matlab.ui.control.NumericEditField
        masknumiterLabel                matlab.ui.control.Label
        maxconstraintEditField          matlab.ui.control.NumericEditField
        maxconstraintLabel              matlab.ui.control.Label
        zernikestolEditField            matlab.ui.control.NumericEditField
        toleranceLabel                  matlab.ui.control.Label
        optimizationzernikeranksEditField  matlab.ui.control.NumericEditField
        zernikeranksEditField_2Label    matlab.ui.control.Label
        IterativeoptimizationCheckBox   matlab.ui.control.CheckBox
        PreviewButton                   matlab.ui.control.Button
        subaperturemarginEditField      matlab.ui.control.NumericEditField
        subaperturemarginEditFieldLabel  matlab.ui.control.Label
        subaperturesEditField           matlab.ui.control.NumericEditField
        subaperturesEditFieldLabel      matlab.ui.control.Label
        shackhartmannzernikeranksEditField  matlab.ui.control.NumericEditField
        zernikeranksEditFieldLabel      matlab.ui.control.Label
        ShackHarmannCheckBox            matlab.ui.control.CheckBox
        Label_2                         matlab.ui.control.Label
        UIAxes_1                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        FileselectionPanel              matlab.ui.container.Panel
        fsEditField                     matlab.ui.control.NumericEditField
        fsEditFieldLabel                matlab.ui.control.Label
        lowmemoryCheckBox               matlab.ui.control.CheckBox
        rephasingCheckBox               matlab.ui.control.CheckBox
        saveadditionalvideosCheckBox    matlab.ui.control.CheckBox
        refbatchsizeEditField           matlab.ui.control.NumericEditField
        refbatchsizeEditFieldLabel      matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        DyEditField                     matlab.ui.control.NumericEditField
        DyEditFieldLabel                matlab.ui.control.Label
        DxEditField                     matlab.ui.control.NumericEditField
        DxEditFieldLabel                matlab.ui.control.Label
        lowfrequencyCheckBox            matlab.ui.control.CheckBox
        phaseregistrationCheckBox       matlab.ui.control.CheckBox
        SVDCheckBox                     matlab.ui.control.CheckBox
        AutofocusButton                 matlab.ui.control.Button
        saverawvideosCheckBox           matlab.ui.control.CheckBox
        temporalfilterEditField         matlab.ui.control.NumericEditField
        positioninfileSlider            matlab.ui.control.Slider
        positioninfileSliderLabel       matlab.ui.control.Label
        ParallelismDropDown             matlab.ui.control.DropDown
        temporalfilterCheckBox          matlab.ui.control.CheckBox
        imageregistrationCheckBox       matlab.ui.control.CheckBox
        wavelengthEditField             matlab.ui.control.NumericEditField
        wavelengthEditFieldLabel        matlab.ui.control.Label
        zEditField                      matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        batchstrideEditField            matlab.ui.control.NumericEditField
        batchstrideEditFieldLabel       matlab.ui.control.Label
        batchsizeEditField              matlab.ui.control.NumericEditField
        batchsizeEditFieldLabel         matlab.ui.control.Label
        ImagerenderingButton            matlab.ui.control.Button
        LoadfileButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        % skip callbacks if no file_M0 is loaded
        file_loaded
        
        filename % current file_M0 loaded
        filepath
        extension % .raw or .cine
        interferogram_stream
        
        % data related to current displayed frame
        frame_batch
        
        % misc proxy data
        Nx
        Ny
        Fs
        pix_width
        pix_height
        gw
        kernel
        FH
        hologram
        DX % x circshift for registration
        DY % y circshift for registration
        correction_coefs = [];
        correction_zernikes = [];
        previous_zernike_indices = []; % shack-hartmann coefficients not to recalculate them every time
        OCTstack
        
        
        % misc
        cache % cache containg all parameters
        rephasing_data % data containing correction phase computed previously
    end
    
    methods (Access = public)
        function compute_kernel(app, use_gpu)
            app.kernel = propagation_kernel(app.Nx, app.Ny, app.zEditField.Value, app.wavelengthEditField.Value, app.pix_width, app.pix_height, use_gpu);
        end
    
        function compute_FH(app, use_gpu)
            if use_gpu
                app.FH = gpuArray(fftshift(fft2(app.frame_batch)) .* app.kernel); 
            else
                app.FH = fftshift(fft2(app.frame_batch)) .* app.kernel;
            end
            
            if app.rephasingCheckBox.Value
                app.FH = rephase_FH(app.FH, app.rephasing_data, app.batchsizeEditField.Value, app.positioninfileSlider.Value);
            end
        end
    
        function compute_hologram(app, use_gpu)
            if ~app.file_loaded
                return;
            end
            
            %FIXME : change function name DopplerAcquisition ->
            %HologramRenderingParameters & Remove Fs ?
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.Fs/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pix_width,app.pix_height);

            switch app.ImageChoiceDropDown.Value
                case 'Power doppler'
                    %preview image
                    app.hologram = reconstruct_hologram(app.FH, app.f1EditField.Value, app.f2EditField.Value, acquisition, app.gw, use_gpu, app.SVDCheckBox.Value);
                case 'Color image'
                    %preview image
                    [~,~,~,~,freq_low,freq_high] = reconstruct_hologram_extra(app.FH, app.cache.wavelength, app.f1EditField.Value, app.f2EditField.Value, acquisition, app.gw, use_gpu, app.SVDCheckBox.Value,...
                                                                [], app.compositef1EditField.Value, app.compositef2EditField.Value, app.compositef3EditField.Value);
            
                    if app.lowfrequencyCheckBox.Value
                        app.hologram = construct_colored_image(-gather(freq_high), -gather(freq_low), app.lowfrequencyCheckBox.Value);
                    else
                        app.hologram = construct_colored_image(gather(freq_low), gather(freq_high), app.lowfrequencyCheckBox.Value);
                    end
                case '3D view'
                    %preview image
                    app.hologram = reconstruct_hologram(app.FH, app.f1EditField.Value, app.f2EditField.Value, acquisition, app.gw, use_gpu, app.SVDCheckBox.Value);
                otherwise
                    error('unreachable statement reached');
            end
            
            app.hologram = flip(app.hologram);
        end
    
       
        function show_hologram(app)
            if ~app.file_loaded
                return;
            end
            
            switch app.ImageChoiceDropDown.Value
                case 'Power doppler'
                    if app.lowfrequencyCheckBox.Value
                        sign = -1;
                    else
                        sign = 1;
                    end
                case 'Color image'
                    sign = 1;
                case '3D view'
                    if app.lowfrequencyCheckBox.Value
                        sign = -1;
                    else
                        sign = 1;
                    end
                otherwise
                    error('unreachable statement reached');
            end
            
            imshow((mat2gray(sign*app.hologram)), 'Parent', app.UIAxes_1,...
                   'XData', [1 app.UIAxes_1.Position(3)], ...
                   'YData', [1 app.UIAxes_1.Position(4)]);
        end
    
    end
    
    methods (Access = private)   
        function create_cache(app)
            c = GuiCache();
            
            c.batch_size = app.batchsizeEditField.Value;
            c.batch_stride = app.batchstrideEditField.Value;
            c.z = app.zEditField.Value;
            c.f1 = app.f1EditField.Value;
            c.f2 = app.f2EditField.Value;
            c.wavelength = app.wavelengthEditField.Value;
            c.Fs = app.Fs;
            c.pix_width = app.pix_width;
            c.pix_height = app.pix_height;
            c.registration = app.imageregistrationCheckBox.Value;
            c.temporal_filter_flag = app.temporalfilterCheckBox.Value;
            c.temporal_filter = app.temporalfilterEditField.Value;
            c.parallelism = app.ParallelismDropDown.Value;
            c.registration_via_phase = app.phaseregistrationCheckBox.Value;
            c.aberration_compensation = app.IterativeoptimizationCheckBox.Value;
            
            c.notes = app.NotesTextArea.Value;
            c.DX = app.DX;
            c.DY = app.DY;
            c.position_in_file = app.positioninfileSlider.Value;
            c.save_additional_videos = app.saveadditionalvideosCheckBox.Value;
            c.low_memory = app.lowmemoryCheckBox.Value;
            c.rephasing = app.rephasingCheckBox.Value;
            
            c.color_f1 = app.compositef1EditField.Value;
            c.color_f2 = app.compositef2EditField.Value;
            c.color_f3 = app.compositef3EditField.Value;
            c.low_frequency = app.lowfrequencyCheckBox.Value;
            
            % iterative optimization parameters
            c.iterative_aberration_compensation = app.IterativeoptimizationCheckBox.Value;
            c.optimization_zernike_ranks = app.optimizationzernikeranksEditField.Value;
            c.mask_num_iter = app.masknumiterEditField.Value;
            c.zernikes_tol = app.zernikestolEditField.Value;
            c.max_constraint = app.maxconstraintEditField.Value;
            
            % shack-hartmann parameters
            c.shack_hartmann_aberration_compensation = app.ShackHarmannCheckBox.Value;
            c.shack_hartmann_zernike_ranks = app.shackhartmannzernikeranksEditField.Value;
            c.num_subapertures = app.subaperturesEditField.Value;
            c.subaperture_margin = app.subaperturemarginEditField.Value;
            
            app.cache = c;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set app constants
            app.gw = 35;
            app.file_loaded = false;
            app.DX = 0;
            app.DY = 0;
            
            % add subfolders to path
            addpath('reconstruction');
            addpath('post_processing');
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            %% open file and manage file extension
            [fname, fpath] = uigetfile('*.raw;*.cine;*.holo');
            if fname == 0
                return
            end
            app.Label_2.Text = '';
            [~, ~, ext] = fileparts(fname);
            app.extension = ext;
            
            switch ext
                case '.cine'
                    app.filename = fname;
                    app.filepath = fpath;
                    
                    app.interferogram_stream = CineReader(fullfile(app.filepath, app.filename));
                    
                    % fetch data from cine file
                    app.Fs = double(app.interferogram_stream.frame_rate);
                    
                    % set f values from Fs
                    if app.Fs > 20000
                        app.lowfrequencyCheckBox.Value = false;
                        app.f1EditField.Value = 6;
                        app.f2EditField.Value = floor(app.Fs / (2*1000));
                        
                        app.compositef1EditField.Value = 2;
                        app.compositef2EditField.Value = 10;
                        app.compositef3EditField.Value = floor(app.Fs / (2*1000));
                    elseif app.Fs > 2000
                        app.lowfrequencyCheckBox.Value = true;
                        
                        app.f1EditField.Value = 0.2;
                        app.f2EditField.Value = floor(app.Fs / (2*1000));
                        
                        app.compositef1EditField.Value = 0.2;
                        app.compositef2EditField.Value = 1;
                        app.compositef3EditField.Value = floor(app.Fs / (2*1000));
                    else
                        app.lowfrequencyCheckBox.Value = true;
                        
                        app.f1EditField.Value = floor(app.Fs / (8*1000));
                        app.f2EditField.Value = floor(app.Fs / (2*1000));
                        
                        app.compositef1EditField.Value = floor(app.Fs / (8*1000));
                        app.compositef2EditField.Value = floor(app.Fs / (4*1000));
                        app.compositef3EditField.Value = floor(app.Fs / (2*1000));
                    end     
                    
                    % update GUI text
                    color_f1 = app.compositef1EditField.Value;
                    color_f2 = app.compositef2EditField.Value;
                    color_f3 = app.compositef3EditField.Value;
                    
                    if ~app.lowfrequencyCheckBox.Value
                        app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f1, color_f2);
                        app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f2, color_f3);
                    else
                        app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f2, color_f3);
                        app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f1, color_f2);
                    end
                    
                    app.pix_width = 1 / double(app.interferogram_stream.horizontal_pix_per_meter);
                    app.pix_height = 1 / double(app.interferogram_stream.vertical_pix_per_meter);
                    app.Nx = double(app.interferogram_stream.frame_width);
                    app.Ny = double(app.interferogram_stream.frame_height);
                case '.raw'
                    app.filename = fname;
                    app.filepath = fpath;
                    
                    % raw file - fetching missing data from user
                    
                    % Dialog box
                    prompt = {'Nx:', 'Ny:', 'Fs:', 'pix width:', 'pix height:', 'endianness (b/l):'};
                    dlgtitle = 'Raw file detected - Input missing data';
                    dims = [1 35];
                    definput = {'512', '512', '67000', '28e-6', '28e-6', 'l'};
                    
                    answer = inputdlg(prompt, dlgtitle, dims, definput);
                    frame_width = str2num(answer{1});
                    frame_height = str2num(answer{2});
                    app.Fs = str2num(answer{3});
                    app.pix_width = str2double(answer{4});
                    app.pix_height = str2double(answer{5});
                    endianness = answer{6};
                    
                    if isempty(frame_width) || isempty(frame_height) || isempty(app.Fs) || isempty(app.pix_width) || isempty(app.pix_height) || isempty(endianness)                     
                        error('Bad raw data input - file could not be loaded correctly');
                    end
                    
                    app.f2EditField.Value = floor(app.Fs / (2*1000));
                    app.compositef3EditField.Value = floor(app.Fs / (2*1000));

                    acquisition = DopplerAcquisition(frame_width, frame_height,app.Fs/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pix_width,app.pix_height);
                    app.interferogram_stream = RawReader(fullfile(app.filepath, app.filename), endianness, acquisition, app.batchsizeEditField.Value, app.batchstrideEditField.Value);
            
                    app.Nx = app.interferogram_stream.acquisition.Nx;
                    app.Ny = app.interferogram_stream.acquisition.Ny;
                case '.holo'
                    app.filename = fname;
                    app.filepath = fpath;
                    
                    app.interferogram_stream = HoloReader(fullfile(app.filepath, app.filename));
                    
                    app.zEditField.Value = app.interferogram_stream.footer.z;
                    app.wavelengthEditField.Value = app.interferogram_stream.footer.lambda;
                    
                    app.Nx = single(app.interferogram_stream.frame_width);
                    app.Ny = single(app.interferogram_stream.frame_height);
                    app.pix_width = app.interferogram_stream.footer.pixel_size * 1e-6;
                    app.pix_height = app.interferogram_stream.footer.pixel_size * 1e-6;
                    
                    % TODO
                    % for now set default Fs
                    app.Fs = 1500;
                    
                    app.f1EditField.Value = 0.1;
                    app.f2EditField.Value = app.Fs / (2*1000);
                    
                    app.compositef1EditField.Value = 0.2;
                    app.compositef2EditField.Value = 1;
                    app.compositef3EditField.Value = floor(app.Fs / (2*1000));
                    
                    app.lowfrequencyCheckBox.Value = false;
            end
            
            % display file name in GUI
            app.CurrentFilePanel.Title = sprintf("Current file: %s", fullfile(app.filepath, app.filename));         
            app.fsEditField.Value = app.Fs/1000;
            
            % change position slider limits according to number of frames in the file
            app.positioninfileSlider.Limits = [0, double(app.interferogram_stream.num_frames-app.batchsizeEditField.Value)];
            app.positioninfileSlider.Value = 0;
            app.EditField.Limits = [0, double(app.interferogram_stream.num_frames-app.batchsizeEditField.Value)];
            app.EditField.Value = 0;
            
            %% Load settings from previous runs if available
            %  This might not work, for instance if previous runs were made
            %  with a different version of the software, such that the
            %  cache structure is different and cannot be loaded
            try
                [cache, cache_found] = fetch_cache(app.filepath, app.filename, ext);
            catch
                % the directory is present but is missing files or contains
                % corrupt data
                cache = [];
                cache_found = false;
            end
            
            if cache_found
                try
                    % set gui parameters from cache
                    app.batchsizeEditField.Value = cache.batch_size;
                    app.batchstrideEditField.Value = cache.batch_stride;
                    app.zEditField.Value = cache.z;
                    app.f1EditField.Value = cache.f1;
                    app.f2EditField.Value = cache.f2;
                    app.wavelengthEditField.Value = cache.wavelength;
                    
                    app.saveadditionalvideosCheckBox.Value = cache.save_additional_videos;
                    app.lowmemoryCheckBox.Value = cache.low_memory;
                    app.rephasingCheckBox.Value = cache.rephasing;
                    
                    app.NotesTextArea.Value = cache.notes;
                    app.positioninfileSlider.Value = cache.position_in_file;
                    app.EditField.Value = cache.position_in_file;
                    
                    app.IterativeoptimizationCheckBox.Value = cache.iterative_aberration_compensation;
                    app.masknumiterEditField.Value = cache.mask_num_iter;
                    app.zernikestolEditField.Value = cache.low_order_zernikes;
                    app.maxconstraintEditField.Value = cache.low_order_max_constraint;
                    
                    app.ShackHarmannCheckBox.Value = cache.shack_hartmann_aberration_compensation;
                    app.shackhartmannzernikeranksEditField.Value = cache.shack_hartmann_zernike_ranks;
                    app.subaperturesEditField.Value = cache.num_subapertures;
                    app.subaperturemarginEditField.Value = cache.subaperture_margin;
                    
                    app.compositef1EditField.Value = cache.color_f1;
                    app.compositef2EditField.Value = cache.color_f2;
                    app.compositef3EditField.Value = cache.color_f3;
                    app.lowfrequencyCheckBox.Value = cache.low_frequency;
                catch
                  % an expection may have been thrown if the .mat file does
                  % not contain all of the required fields (e.g. if the .mat file was generated by
                  % an older version of the software). This is not a bug.
                  
                  % do nothing
                end
            else
                % set default gui parameters
                
                % do nothing, default parameters are already set in the GUI
                % toolbox
            end
            
            try
                [rephasing_data, rephasing_found] = fetch_rephasing_data(app.filepath, app.filename, ext);
            catch
                rephasing_data = [];
                rephasing_found = false;
            end
            
            if rephasing_found
                app.rephasing_data = rephasing_data;
                app.NotesTextArea.Value = {''};
                for i = 1:numel(rephasing_data)
                    r = rephasing_data(i);
                    reg = ~isempty(r.aberration_correction.rephasing_zernike_coefs);
                    shack = ~isempty(r.aberration_correction.shack_hartmann_zernike_coefs);
                    opt = ~isempty(r.aberration_correction.iterative_opt_zernike_coefs);
                    text = sprintf('Run %d:\nsize = %d, stride = %d, reg = %s, shack = %s, opt = %s', i, r.batch_size, r.batch_stride, string(reg), string(shack), string(opt));
                    
                    % compute mean correction
                    if shack 
                        mean_1 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(1,:));
                        mean_2 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(2,:));
                        mean_3 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(3,:));
                    end
                    
                    if opt
                        mean_1 = mean(r.aberration_correction.iterative_opt_zernike_coefs(1,:));
                        mean_2 = mean(r.aberration_correction.iterative_opt_zernike_coefs(2,:));
                        mean_3 = mean(r.aberration_correction.iterative_opt_zernike_coefs(3,:));
                    end
                    
                    if shack && opt
                        mean_1 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(1,:) + r.aberration_correction.iterative_opt_zernike_coefs(1,:));
                        mean_2 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(2,:) + r.aberration_correction.iterative_opt_zernike_coefs(2,:));
                        mean_3 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(3,:) + r.aberration_correction.iterative_opt_zernike_coefs(3,:));
                    end
                    
                    if shack || opt
                        text = sprintf('%s\nastig 1 = %0.1f, defocus = %0.1f, astig 2 = %0.1f', text, mean_1, mean_2, mean_3);
                    end
                    
                    text = sprintf('%s\n',text);
                    
                    app.NotesTextArea.Value = [{text}; app.NotesTextArea.Value];
                end
                app.NotesTextArea.Value = [{'Rephasing found:'}; app.NotesTextArea.Value];
            else
                app.NotesTextArea.Value = {''};
            end

            % load data required to display images in the GUI
            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, floor(app.positioninfileSlider.Value));
            
            %% display images on file load
            
            % compute FH
            app.file_loaded = true;
            app.compute_kernel(false);
            app.compute_FH(true);
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: zEditField
        function zEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: f1EditField
        function f1EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: f2EditField
        function f2EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: wavelengthEditField
        function wavelengthEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: batchsizeEditField
        function batchsizeEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.positioninfileSlider.Limits = [0, double(app.interferogram_stream.num_frames-app.batchsizeEditField.Value)];
            app.positioninfileSlider.Value = min(app.positioninfileSlider.Value, app.positioninfileSlider.Limits(2));
            app.EditField.Limits = [0, double(app.interferogram_stream.num_frames-app.batchsizeEditField.Value)];
            app.EditField.Value = min(app.EditField.Value, app.EditField.Limits(2));
            
            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, 0);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Button pushed function: ImagerenderingButton
        function ImagerenderingButtonPushed(app, event)
            % main program loop
            % Select a file and call other functions
            % to perform a full computation and create
            % output videos.
            
            if ~app.file_loaded
                return
            end
            
            % start clock to monitor
            % computation duration
            tic;
            
            % save all gui parameters to a struct.
            % The current computation will fetch every parameter
            % from the cache and not from the gui.
            % The purpose of this is to prevent gui interactions
            % to mess up the parameters while a computation is ongoing.
            app.create_cache();
            
            aberration_correction = AberrationCorrection();
            image_registration = ImageRegistration();
            
            % select parallelism policy
            switch app.cache.parallelism
                case 'CPU multithread'
                    parfor_arg = Inf;
                    use_multithread = true;
                    use_gpu = false;
                case 'GPU parallelization'
                    parfor_arg = 0;
                    use_multithread = false;
                    use_gpu = true;
                case 'CPU singlethread'
                    parfor_arg = 0;
                    use_multithread = false;
                    use_gpu = false;
                case 'CPU/GPU rendering/optim'
                    % TODO
                    parfor_arg = Inf;
                    use_multithread = true;
                    use_gpu = true;
            end
            
            % create proxy variables so that the entire gui
            % is not broadcasted to workers
            % in the parfor loops
            istream = app.interferogram_stream;
            j_win = app.cache.batch_size;
            j_step = app.cache.batch_stride;
            local_f1 = app.cache.f1;
            local_f2 = app.cache.f2;
            local_gw = app.gw;
            local_Nx = app.Nx;
            local_Ny = app.Ny;
            local_wavelength = app.cache.wavelength;
            local_svd = app.SVDCheckBox.Value;
            local_save_additional_videos = app.cache.save_additional_videos;
            local_rephasing = app.cache.rephasing;
            local_low_memory = app.cache.low_memory;
            if local_rephasing
                local_rephasing_data = app.rephasing_data;
            else
                local_rephasing_data = [];
            end
            
            local_kernel = app.kernel;
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.cache.Fs/1000,app.cache.z,app.cache.wavelength,app.cache.DX,app.cache.DY,app.cache.pix_width,app.cache.pix_height);
            
            local_low_frequency = app.cache.low_frequency;
            local_color_f1 = app.cache.color_f1;
            local_color_f2 = app.cache.color_f2;
            local_color_f3 = app.cache.color_f3;
            
            % generate output directory
            output_dirname = create_output_directory_name(app.filepath, app.filename);
            output_dirpath = fullfile(app.filepath, output_dirname);
            mkdir(output_dirpath);
            mkdir(fullfile(output_dirpath, 'avi'));
            mkdir(fullfile(output_dirpath, 'mp4'));
            mkdir(fullfile(output_dirpath, 'png'));
            if app.saverawvideosCheckBox.Value
                mkdir(fullfile(output_dirpath, 'raw'));
            end
            
            % allocate video buffers
            num_batches = floor((app.interferogram_stream.num_frames - app.cache.batch_size) / app.cache.batch_stride);
            if local_low_memory
                [~, output_dirname] = fileparts(output_dirpath);
                output_filename = sprintf('%s_M0_tmp.%s', output_dirname, 'raw');
                fd_M0 = fopen(sprintf('%s\\%s', output_dirpath, output_filename), 'w');
            else
                
                if not(app.Nx == app.Ny)
                    video_M0 = zeros(app.Ny,app.Nx,1,num_batches,'single');
                else
                    video_M0 = zeros(app.Nx,app.Ny,1,num_batches,'single');
                end
                
                if local_save_additional_videos
                    video_M1 = video_M0;
                    video_M2 = video_M0;
                    video_M1_over_M0 = video_M0;
                    video_M2_over_M0 = video_M0;
                    video_M_freq_low = video_M0;
                    video_M_freq_high = video_M0;
                    video_M0_pos = video_M0;
                    video_M0_neg = video_M0;
                    video_M1sM0r = video_M0;
                end
            end
            
            % parfor waitbar
            % we have to use a DataQueue
            % since there are no synchronization
            % primitives in matlab (mutex)
            D = parallel.pool.DataQueue;
            h = waitbar(0, '');
            afterEach(D, @update_waitbar);
            N = double(num_batches-1);
            p = 1;
                
            %% first pass - compute shifts for video registration
            registration_pass = app.cache.registration_via_phase;
            
            if registration_pass
                current_batch_idx = 1 + floor(app.positioninfileSlider.Value / (app.cache.batch_size + app.cache.batch_stride));
                shifts = compute_temporal_registration(istream, app.cache, j_win, j_step, current_batch_idx, local_gw,...
                                                       local_kernel,[], D, use_multithread);   
                % tilts zernikes used for translating an image
                zernikes = evaluate_zernikes([1 1], [1 -1], app.Nx, app.Ny);
            else
                % declare unused variables to make matlab parfor
                % loop parser happy - stupid matlab
                shifts = zeros(2, num_batches,'single');
                zernikes = zeros(app.Nx, app.Ny, 2, 'single');
            end
            
            enable_iterative_optimization = app.IterativeoptimizationCheckBox.Value;
            enable_shack_hartmann = app.ShackHarmannCheckBox.Value;
            
%             app.correction_coefs = [];
%            app.correction_zernikes = [];
%            app.previous_zernike_indices = [];
            
            if enable_shack_hartmann
                % aberration computation pass
                %
                % Implementation - Shack Hartmann simulation
                
                % shack hartmann params
                zernike_ranks = app.cache.shack_hartmann_zernike_ranks;
                num_subapertures = app.cache.num_subapertures;
                subaperture_margin = app.cache.subaperture_margin;
                
                calibration_factor = 20;
                corrmap_margin = 0.4;
                
                power_filter_corrector = 1;
                sigma_filter_corrector = 1;
                
                % select subapertures to exclude, depending on the number
                % of subaperture used. This is chosen experimentally, the
                % value of the number of subapertures is constrained in the
                % GUI so the following switch should always branch to a
                % valid choice of excluded_subapertures
                switch num_subapertures
                    case 4
                        excluded_subapertures = [1; 4; 13; 16];
                    case 5
                        excluded_subapertures = [1; 5; 21; 25];
                    case 6
                        excluded_subapertures = [1; 2; 5; 6; 7; 12; 25; 30; 31; 32; 35; 36];
                    case 7
                        excluded_subapertures = [1; 2; 6; 7; 8; 14; 36; 42; 43; 44; 48; 49];
                    case 8
                        excluded_subapertures = [1; 2; 7; 8; 9; 16; 49; 56; 57; 58; 63; 64];
                    otherwise
                        error('Unreachable code was reached. Check value of num_subapertures');
                end
                
                % perform iterative aberration computation with shack-hartmann
                for r = 2:zernike_ranks
                    send(D, 3); % reset progress bar
                    
                    % r-th zernike line has r+1 zernikes in it
                    cur_num_zernikes = r + 1;
                    
                    
                    % compute indices of the zernikes of current rank
                    current_zernike_indices = (cur_num_zernikes * (cur_num_zernikes - 1) / 2):(cur_num_zernikes * (cur_num_zernikes - 1) / 2 + cur_num_zernikes - 1);
                    
                    % apply previous aberration correction
                    [current_correction_coefs, stiched_moments_video] = compute_correction_shack_hartmann(istream,app.cache,local_kernel,local_rephasing_data,app.gw,[],D,use_gpu,use_multithread,current_zernike_indices,...
                                                                     shifts,num_subapertures,calibration_factor,subaperture_margin,corrmap_margin,...
                                                                     excluded_subapertures,power_filter_corrector,sigma_filter_corrector,...
                                                                    app.previous_zernike_indices, app.correction_coefs);
                    [~, current_correction_zernikes] = zernike_phase(current_zernike_indices, app.Nx, app.Ny);
                                                                 
                    app.correction_coefs = [app.correction_coefs; current_correction_coefs];
                   app.correction_zernikes = cat(3,app.correction_zernikes, current_correction_zernikes);
                   app.previous_zernike_indices = [app.previous_zernike_indices, current_zernike_indices];
                end
                
                % save correction coefs and indices for future export
                aberration_correction.shack_hartmann_zernike_indices =app.previous_zernike_indices;
                aberration_correction.shack_hartmann_zernike_coefs = app.correction_coefs;
            end
            
            if enable_iterative_optimization
                % aberration computation pass
                %
                % Implementation - Entropy optimization
                %
                % here we generate a set of zernike coefficients
                % that represent the aberation present on the
                % reconstructed images
                
                % compute coefficients for high order zernikes
                % we make a different optimization pass for each
                % additional zernike rank
                
                % reset correction indices and coefs arrays that ay have
                % been set by the shack hartmann pass
                zernike_indices = [];
                app.correction_coefs = [];
                
                zernike_ranks = app.optimizationzernikeranksEditField.Value;
                for r = 2:zernike_ranks
                    send(D, 4); % reset progress bar
                    num_zernikes = r + 1; % map to zernfun zernike ordering
                    
                    % construct high order zernike indices
                    current_zernike_indices = (num_zernikes * (num_zernikes - 1) / 2):(num_zernikes * (num_zernikes - 1) / 2 + num_zernikes - 1);
                    
                    % compute correction
                    current_cor = compute_correction(istream,app.cache,local_kernel,local_rephasing_data,app.gw,[],D,use_gpu,use_multithread,current_zernike_indices,app.cache.zernikes_tol,app.cache.mask_num_iter,...
                                                     app.cache.max_constraint,shifts, app.previous_zernike_indices, app.correction_coefs);
                    [~, current_correction_zernikes] = zernike_phase(current_zernike_indices, app.Nx, app.Ny);
                    
                    % append coefs to correction coefs array
                    app.correction_coefs = [app.correction_coefs; current_cor];
                   app.correction_zernikes = cat(3,app.correction_zernikes, current_correction_zernikes);
                   app.previous_zernike_indices = [app.previous_zernike_indices current_zernike_indices];
                    zernike_indices = [zernike_indices current_zernike_indices];
                end
                
                % save correction coefs and indices for future export
                aberration_correction.iterative_opt_zernike_indices = app.zernike_indices;
                aberration_correction.iterative_opt_zernike_coefs = app.correction_coefs;
            end
            
            if ~enable_shack_hartmann && ~enable_iterative_optimization % no aberration compensation at all
                % define dummy unused variables with good size
                % to make matlab parfor parser happy - stupid matlab
                app.correction_coefs = zeros(3, istream.num_frames, 'single');
               app.correction_zernikes = zeros(app.Nx, app.Ny, 3, 'single');
            end
           
            [rephasing_zernikes, shack_zernikes, iterative_zernikes] = aberration_correction.generate_zernikes(app.Nx, app.Ny);
            
            %% Final pass
            % Here we apply all transfromations computed in previous passes
            % and construct the videos
            
            % There are two ways of doing this, the first one is a 'low
            % memory' mode in which the reconstructed data is cached on a
            % file on the disk to avoid allocating massive video buffers.
            % This is useful when the batch stride is so small that the
            % whole video buffer would overflow memory.
            %
            % The second one is a more straightforward mode in which whole
            % video buffers are allocated and stored in RAM. This is the
            % default and most simple mode
            if local_low_memory
                reg_frame_batch = app.interferogram_stream.read_frame_batch(app.refbatchsizeEditField.Value, floor(app.positioninfileSlider.Value));
                reg_FH = fftshift(fft2(reg_frame_batch)) .* app.kernel;
                reg_FH = rephase_FH(reg_FH,local_rephasing_data,app.refbatchsizeEditField.Value,floor(app.positioninfileSlider.Value));
                acquisition = DopplerAcquisition(app.Nx,app.Ny,app.Fs/1000,app.cache.z,app.cache.wavelength,app.cache.DX,app.cache.DY,app.pix_width,app.pix_height);
                reg_hologram = reconstruct_hologram(reg_FH, app.cache.f1, app.cache.f2, acquisition, app.gw, use_gpu, app.SVDCheckBox.Value);
                
                block_size = 1024; % TODO: this should stay hardcoded but to a higher value
                block_M0 = zeros(app.Nx,app.Ny,1,block_size);
                send(D,-2); % display 'video construction' on progress bar
                for batch_idx = 1:block_size:num_batches
                    last_iter = min(batch_idx - 1 + block_size, num_batches) - batch_idx + 1;
                    parfor (inner_idx = 1:last_iter, parfor_arg)
                        frame_batch = istream.read_frame_batch(j_win, (batch_idx - 1 + inner_idx - 1) * j_step);
                        FH = compute_FH_from_frame_batch(frame_batch, local_kernel);
                        
                        if local_rephasing
                            FH = rephase_FH(FH, local_rephasing_data, j_win, (batch_idx - 1 + inner_idx - 1) * j_step);
                        end
                        
                        % add tilted phase for FH registration
                        if registration_pass
                            FH = register_FH(FH, shifts(:, batch_idx:(batch_idx+1)-1), j_win, 1);
                        end
                        
                        if enable_iterative_optimization || enable_shack_hartmann
                            phase = aberration_correction.compute_total_phase(batch_idx,rephasing_zernikes,shack_zernikes,iterative_zernikes);
                            correction = exp(-1i * phase);
                            
                            % apply correction
                            FH = FH .* correction;
                        end
        
                        M0 = reconstruct_hologram(FH, local_f1, local_f2, acquisition, local_gw, false, local_svd, []);
                        
                        % save frame to block buffer
                        block_M0(:,:,:,inner_idx) = gather(M0);
                        
                        send(D, 0);
                    end
                    
                    if app.cache.registration
                        [block_M0, block_shifts] = register_video_from_reference(block_M0(:,:,:,1:last_iter),reg_hologram);   
                        shifts(:,batch_idx:batch_idx+last_iter-1) = block_shifts;
                    end
                    
                    % write block to file
                    fwrite(fd_M0,block_M0(:),'single');
                end
                fclose(fd_M0);
                
                if app.cache.registration
                    % save shifts for export
                    image_registration.translation_x = shifts(1,:);
                    image_registration.translation_y = shifts(2,:);
                end
                
                export_raw = app.saverawvideosCheckBox.Value;
                generate_video_low_memory(sprintf('%s\\%s', output_dirpath, output_filename), app.Nx, app.Ny, 1, num_batches, output_dirpath, 'M0', 0.0005, app.cache.temporal_filter, local_low_frequency, export_raw, true);
            else % ~local_low_memory
                send(D,-2); % display 'video construction' on progress bar
                parfor (batch_idx = 1:num_batches, parfor_arg)                 
                    frame_batch = istream.read_frame_batch(j_win, (batch_idx - 1) * j_step);
                    FH = compute_FH_from_frame_batch(frame_batch, local_kernel); 
                    
                    if local_rephasing
                        FH = rephase_FH(FH, local_rephasing_data, j_win, (batch_idx - 1) * j_step);
                    end
                    
                    % add tilted phase for FH registration
                    if registration_pass
                        FH = register_FH(FH, shifts(:, batch_idx:(batch_idx+1)-1), j_win, 1);
                    end
                    
                    if enable_iterative_optimization || enable_shack_hartmann
                        phase = aberration_correction.compute_total_phase(batch_idx,rephasing_zernikes,shack_zernikes,iterative_zernikes);
                        correction = exp(-1i * phase);
                        
                        % apply correction
                        FH = FH .* correction;
                    end
    
                    if local_save_additional_videos
                        [M0, sqrt_M0, M1, M2, M_freq_low, M_freq_high, M0_pos, M0_neg, M1sM0r, velocity] = reconstruct_hologram_extra(FH, local_wavelength, local_f1, local_f2, acquisition, local_gw, false, local_svd, [],...
                                                                                          local_color_f1, local_color_f2, local_color_f3);
                        video_M0(:,:,:,batch_idx) = gather(M0);
                        video_M1(:,:,:,batch_idx) = gather(M1);
                        video_M2(:,:,:,batch_idx) = gather(M2);
                        video_M1_over_M0(:,:,:,batch_idx) = gather(M1) ./ gather(M0);
                        video_M2_over_M0(:,:,:,batch_idx) = gather(M2) ./ gather(sqrt_M0);
                        video_M_freq_low(:,:,:,batch_idx) = gather(M_freq_low);
                        video_M_freq_high(:,:,:,batch_idx) = gather(M_freq_high);
                        video_M0_pos(:,:,:,batch_idx) = gather(M0_pos);
                        video_M0_neg(:,:,:,batch_idx) = gather(M0_neg);
                        video_M1sM0r(:,:,:,batch_idx) = gather(M1sM0r);
                        video_velocity(:,:,:,batch_idx) = gather(velocity);
                    else
                        M0 = reconstruct_hologram(FH, local_f1, local_f2, acquisition, local_gw, false, local_svd, []);
                        video_M0(:,:,:,batch_idx) = gather(M0);
                    end
                    send(D, 0);
                end 
                
                if local_save_additional_videos
                    % generate color video
                    if local_low_frequency
                        video_color = construct_colored_video(video_M_freq_high, video_M_freq_low);
                    else
                        video_color = construct_colored_video(video_M_freq_low, video_M_freq_high);
                    end
                    
                    % generate directional video
                    
                end
            
                if app.cache.registration
                    % post reconstruction image registration.
                    % This registration is performed on the final video,
                    % it does not use tilts zernikes to perform the
                    % registration.
                    
                    % construct reference image
                    reg_frame_batch = app.interferogram_stream.read_frame_batch(app.refbatchsizeEditField.Value, floor(app.positioninfileSlider.Value));
                    reg_FH = fftshift(fft2(reg_frame_batch)) .* app.kernel;
                    reg_FH = rephase_FH(reg_FH,local_rephasing_data,app.refbatchsizeEditField.Value,floor(app.positioninfileSlider.Value));
                    acquisition = DopplerAcquisition(app.Nx,app.Ny,app.Fs/1000,app.cache.z,app.cache.wavelength,app.cache.DX,app.cache.DY,app.pix_width,app.pix_height);
                    reg_hologram = reconstruct_hologram(reg_FH, app.cache.f1, app.cache.f2, acquisition, app.gw, use_gpu, app.SVDCheckBox.Value);
                    [video_M0, shifts] = register_video_from_reference(video_M0, reg_hologram);
                    
                    if local_save_additional_videos
                        video_M1 = register_video_from_shifts(video_M1, shifts);
                        video_M2 = register_video_from_shifts(video_M2, shifts);
                        video_M1_over_M0 = register_video_from_shifts(video_M1_over_M0, shifts);
                        video_M2_over_M0 = register_video_from_shifts(video_M2_over_M0, shifts);
                        video_color = register_video_from_shifts(video_color, shifts);
                        
                        video_M_freq_high = register_video_from_shifts(video_M_freq_high, shifts);
                        video_M_freq_low = register_video_from_shifts(video_M_freq_low, shifts);
                        
                        video_M0_pos = register_video_from_shifts(video_M0_pos, shifts);
                        video_M0_neg = register_video_from_shifts(video_M0_neg, shifts);
                        
                        video_M1sM0r = register_video_from_shifts(video_M1sM0r, shifts);
                        
                        video_velocity = register_video_from_shifts(video_velocity, shifts);
                    end
                    
                    % save shifts for export
                    image_registration.translation_x = shifts(1,:);
                    image_registration.translation_y = shifts(2,:);
                end
                
                if local_save_additional_videos
                    video_directional = construct_directional_video(video_M0_pos, video_M0_neg, app.cache.temporal_filter);
                    video_fmean = construct_fmean_video(video_M1sM0r, video_M0, app.cache.temporal_filter);
                end
               
                export_raw = app.saverawvideosCheckBox.Value;
                generate_video(video_M0, output_dirpath, 'M0', 0.0005, app.cache.temporal_filter, local_low_frequency, export_raw, true);
                if local_save_additional_videos
                    generate_video(video_M1, output_dirpath, 'DopplerAVG', 0.0005, app.cache.temporal_filter, local_low_frequency, export_raw, true);
                    generate_video(video_M2, output_dirpath, 'DopplerRMS', 0.0005, app.cache.temporal_filter, local_low_frequency, export_raw, true);
                    generate_video(video_M1_over_M0, output_dirpath, 'NormalizedDopplerAVG', 0.0005, app.cache.temporal_filter, local_low_frequency, export_raw, true);
                    generate_video(video_M2_over_M0, output_dirpath, 'NormalizedDopplerRMS', 0.0005, app.cache.temporal_filter, local_low_frequency, export_raw, true);
                    
                    % no contrast enhancement for color video, it's already
                    % been done previously
                    generate_video(video_color, output_dirpath, 'Color', [], app.cache.temporal_filter, local_low_frequency, export_raw, false);
                    generate_video(video_directional, output_dirpath, 'Directional', [], [], false, export_raw, true);
                    generate_video(video_fmean, output_dirpath, 'Fmean', [], [], false, export_raw, true);
                    generate_video(video_M0_pos, output_dirpath, 'M0pos', [], app.cache.temporal_filter, local_low_frequency, export_raw, true);
                    generate_video(video_M0_neg, output_dirpath, 'M0neg', [], app.cache.temporal_filter, local_low_frequency, export_raw, true);
                    generate_video(video_velocity, output_dirpath, 'Velocity', [], app.cache.temporal_filter, local_low_frequency, export_raw, true);
                    
                    if enable_shack_hartmann
                        % phase video
                        video_phase = correction_phase_video(aberration_correction, app.Nx, app.Ny);
                        generate_video(video_phase, output_dirpath, 'Phase', [], [], false, false, false);
                        generate_video(stiched_moments_video, output_dirpath, 'StichedMoments', [], [], false, false, false);
                    end
                
                    % generate additional images
                    if local_low_frequency
                        color_img = construct_colored_image(video_M_freq_high, video_M_freq_low, true);
                    else
                        color_img = construct_colored_image(video_M_freq_low, video_M_freq_high, false);
                    end
                    
                    RI = construct_resistivity_index(video_M0);
                    
                    color_img = flip(color_img);
                    RI = flip(RI);
                    
                    color_output_filename = sprintf('%s_%s.%s', output_dirname, 'Color', 'png');
                    RI_output_filename = sprintf('%s_%s.%s', output_dirname, 'Resistivity', 'png');
                    imwrite(color_img, sprintf('%s\\png\\%s', output_dirpath, color_output_filename));
                    imwrite(RI, sprintf('%s\\png\\%s', output_dirpath, RI_output_filename));
                    
                    % ppt presentation
                    generate_ppt(output_dirname, output_dirpath, app.cache.Fs, app.cache.f1, app.cache.f2, fullfile(output_dirpath, output_dirname));
                end
            end % local_low_memory      
            
            function update_waitbar(sig)
                % signal table
                % 0 => increment value
                % 1 => reset for stage 1 (registration)
                % 2 => reset for stage 2 (video_M0 computation)
                switch sig
                    case 0
                        waitbar(p/N, h);
                        p = p + 1;
                    case -1
                        waitbar(0,h,'Translation registration...');
                        p = 1;
                    case -2
                        waitbar(0,h,'Image rendering...');
                        p = 1;
                    case 1
                        waitbar(0,h,'Optimizing defocus...');
                        p = 1;
                    case 2
                        waitbar(0,h,'Optimizing astigmatism...');
                        p = 1;
                    case 3
                        waitbar(0,h,'Shack-Hartmann...')
                        p = 1;
                    otherwise
                        waitbar(0,h,'Iterative optimization...');
                        p = 1;
                end
            end
            
            % export shifts to AberrationCorrection struct as zernikes
            if registration_pass || app.cache.registration
                magic_number = 710;
                aberration_correction.rephasing_zernike_indices = [1 2];
                aberration_correction.rephasing_zernike_coefs = [shifts(1,:)/local_Nx; shifts(2,:)/local_Ny] * magic_number * 2;
            end
            
            % add computed correction to rephasing data
            new_rephasing_data = RephasingData(app.cache.batch_size, app.cache.batch_stride, aberration_correction);
            % update rephasing array
            rephasing_data = [local_rephasing_data new_rephasing_data];

            % export patient informations to text file_M0
            txt_filename = sprintf('%s.txt', output_dirname);
            save_string_cells_to_file(fullfile(output_dirpath, txt_filename), app.cache.notes);

            % export some workspace variables to .mat file
            mat_filename = sprintf('%s.mat', output_dirname);
            cache = app.cache;
            save(fullfile(output_dirpath, mat_filename), 'cache');
            
            save(fullfile(output_dirpath, mat_filename), 'aberration_correction', '-append');
            save(fullfile(output_dirpath, mat_filename), 'image_registration', '-append');
            
            if app.cache.rephasing
                save(fullfile(output_dirpath, mat_filename), 'rephasing_data', '-append');
            end
            
            % close progress bar window
            close(h);
            
            % display elapsed time
            toc;
        end

        % Button pushed function: AutofocusButton
        function AutofocusButtonPushed(app, event)
            if ~app.file_loaded
                return
            end
            
            f = waitbar(1, 'Autofocus in progress. Please wait...');
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.Fs/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pix_width,app.pix_height);

            % Run a Shack-Hartmann simulation on the current frame batch to
            % compute only defocus.
            
            FH = fftshift(fft2(app.frame_batch)) .* app.kernel;
            calibration_factor = 20;
            corrmap_margin = 0.4;
            
            power_filter_corrector = 1;
            sigma_filter_corrector = 1;
            
            % select subapertures to exclude, depending on the number
            % of subaperture used. This is chosen experimentally, the
            % value of the number of subapertures is constrained in the
            % GUI so the following switch should always branch to a
            % valid choice of excluded_subapertures
            switch app.subaperturesEditField.Value
                case 4
                    excluded_subapertures = [1; 4; 13; 16];
                case 5
                    excluded_subapertures = [1; 5; 21; 25];
                case 6
                    excluded_subapertures = [1; 2; 5; 6; 7; 12; 25; 30; 31; 32; 35; 36];
                case 7
                    excluded_subapertures = [1; 2; 6; 7; 8; 14; 36; 42; 43; 44; 48; 49];
                case 8
                    excluded_subapertures = [1; 2; 7; 8; 9; 16; 49; 56; 57; 58; 63; 64];
                otherwise
                    error('Unreachable code was reached. Check value of num_subapertures');
            end
            shack_hartmann = ShackHartmann(app.subaperturesEditField.Value, 4, calibration_factor, app.subaperturemarginEditField.Value, corrmap_margin, power_filter_corrector, sigma_filter_corrector);
            f1 = app.f1EditField.Value;
            f2 = app.f2EditField.Value;
            M_aso = shack_hartmann.construct_M_aso(f1,f2,app.gw,acquisition);
            shifts = shack_hartmann.compute_images_shifts(FH,f1,f2,app.gw,false,true,acquisition);
            
             % remove corners
            M_aso = mat_mask(M_aso, excluded_subapertures);
            shifts = mat_mask(shifts, excluded_subapertures);
        
            % separate x shifts from y shifts
            Y = cat(1, real(shifts), imag(shifts));
            M_aso_concat = cat(1,real(M_aso),imag(M_aso));
        
            % solve linear system
            coefs = M_aso_concat \ Y;   
        
            coefs = coefs * calibration_factor;
            
            z = app.zEditField.Value + 0.0025*coefs(1);

            app.zEditField.Value = z;
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
            
            close(f);
        end

        % Close request function: LaserDopplerHolographyUIFigure
        function LaserDopplerHolographyUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: positioninfileSlider
        function positioninfileSliderValueChanged(app, event)
            value = app.positioninfileSlider.Value;
            app.EditField.Value = value;
            
            if ~app.file_loaded
                return
            end
            
            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, floor(app.positioninfileSlider.Value));
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: DxEditField
        function DxEditFieldValueChanged(app, event)
            value = app.DxEditField.Value;
            
            x_shift = floor(app.Nx * value);
            app.DX = x_shift;
            
            if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: DyEditField
        function DyEditFieldValueChanged(app, event)
            value = app.DyEditField.Value;
            
            y_shift = floor(app.Ny * value);
            app.DY = y_shift;
            if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: SVDCheckBox
        function SVDCheckBoxValueChanged(app, event)
           if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_FH(false);
            
            zernike_ranks = app.shackhartmannzernikeranksEditField.Value;
            num_subapertures = app.subaperturesEditField.Value;
            subaperture_margin = app.subaperturemarginEditField.Value;
            
            calibration_factor = 20;
            corrmap_margin = 0.4;
            
            power_filter_corrector = 1;
            sigma_filter_corrector = 1;
            
            % select subapertures to exclude, depending on the number
            % of subaperture used. This is chosen experimentally, the
            % value of the number of subapertures is constrained in the
            % GUI so the following switch should always branch to a
            % valid choice of excluded_subapertures
            switch num_subapertures
                case 4
                    excluded_subapertures = [1; 4; 13; 16];
                case 5
                    excluded_subapertures = [1; 5; 21; 25];
                case 6
                    excluded_subapertures = [1; 2; 5; 6; 7; 12; 25; 30; 31; 32; 35; 36];
                case 7
                    excluded_subapertures = [1; 2; 6; 7; 8; 14; 36; 42; 43; 44; 48; 49];
                case 8
                    excluded_subapertures = [1; 2; 7; 8; 9; 16; 49; 56; 57; 58; 63; 64];
                otherwise
                    error('Unreachable code was reached. Check value of num_subapertures');
            end
            
            shack_hartmann = ShackHartmann(num_subapertures,[3 4 5],20,subaperture_margin,corrmap_margin,power_filter_corrector,sigma_filter_corrector);
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.Fs/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pix_width,app.pix_height);
            
            M_aso = shack_hartmann.construct_M_aso(app.f1EditField.Value, app.f2EditField.Value,app.gw,acquisition);
            [shifts, stiched_moments_subap, stiched_corr_subapp] = shack_hartmann.compute_images_shifts(app.FH, app.f1EditField.Value, app.f2EditField.Value, app.gw, false, app.SVDCheckBox.Value, acquisition);
            
            % remove corners
            M_aso = mat_mask(M_aso, excluded_subapertures);
            shifts = mat_mask(shifts, excluded_subapertures);
        
            % separate x shifts from y shifts
            Y = cat(1, real(shifts), imag(shifts));
            M_aso_concat = cat(1,real(M_aso),imag(M_aso));
        
            % solve linear system
            coefs = M_aso_concat \ Y;   
        
            coefs = coefs * calibration_factor;
            
            imshow(mat2gray(stiched_moments_subap), 'Parent', app.UIAxes_3,...
                   'XData', [1 app.UIAxes_3.Position(3)], ...
                   'YData', [1 app.UIAxes_3.Position(4)]);
               
            app.PreviewLabel.Text = sprintf('astig_1 : %0.1f\ndefocus: %0.1f\nastig_2 : %0.1f', coefs(1), coefs(2), coefs(3));
        end

        % Value changed function: compositef1EditField
        function compositef1EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            color_f1 = app.compositef1EditField.Value;
            color_f2 = app.compositef2EditField.Value;
            color_f3 = app.compositef3EditField.Value;
            
            if ~app.lowfrequencyCheckBox.Value
                app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f1, color_f2);
                app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f2, color_f3);
            else
                app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f2, color_f3);
                app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f1, color_f2);
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: compositef2EditField
        function compositef2EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            color_f1 = app.compositef1EditField.Value;
            color_f2 = app.compositef2EditField.Value;
            color_f3 = app.compositef3EditField.Value;
            
            if ~app.lowfrequencyCheckBox.Value
                app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f1, color_f2);
                app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f2, color_f3);
            else
                app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f2, color_f3);
                app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f1, color_f2);
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: compositef3EditField
        function compositef3EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            color_f1 = app.compositef1EditField.Value;
            color_f2 = app.compositef2EditField.Value;
            color_f3 = app.compositef3EditField.Value;
            
            if ~app.lowfrequencyCheckBox.Value
                app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f1, color_f2);
                app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f2, color_f3);
            else
                app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f2, color_f3);
                app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f1, color_f2);
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: lowfrequencyCheckBox
        function lowfrequencyCheckBoxValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            color_f1 = app.compositef1EditField.Value;
            color_f2 = app.compositef2EditField.Value;
            color_f3 = app.compositef3EditField.Value;
            
            if ~app.lowfrequencyCheckBox.Value
                app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f1, color_f2);
                app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f2, color_f3);
            else
                app.LowfrequencyrangeLabel.Text = sprintf('Low frequency range: %d..%d', color_f2, color_f3);
                app.HighfrequencyrangeLabel.Text = sprintf('High frequency range: %d..%d', color_f1, color_f2);
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.positioninfileSlider.Value = value;
            
            if ~app.file_loaded
                return
            end
            
            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, floor(app.positioninfileSlider.Value));
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();            
        end

        % Value changed function: rephasingCheckBox
        function rephasingCheckBoxValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            if app.rephasingCheckBox.Value
                try
                    [rephasing_data, rephasing_found] = fetch_rephasing_data(app.filepath, app.filename, app.extension);
                catch
                    rephasing_data = [];
                    rephasing_found = false;
                end
                
                if rephasing_found
                    app.rephasing_data = rephasing_data;
                    
                    app.NotesTextArea.Value = {''};
                    for i = 1:numel(rephasing_data)
                        r = rephasing_data(i);
                        reg = ~isempty(r.aberration_correction.rephasing_zernike_coefs);
                        shack = ~isempty(r.aberration_correction.shack_hartmann_zernike_coefs);
                        opt = ~isempty(r.aberration_correction.iterative_opt_zernike_coefs);
                        text = sprintf('Run %d:\nsize = %d, stride = %d, reg = %s, shack = %s, opt = %s', i, r.batch_size, r.batch_stride, string(reg), string(shack), string(opt));
                        
                        % compute mean correction
                        if shack 
                            mean_1 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(1,:));
                            mean_2 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(2,:));
                            mean_3 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(3,:));
                        end
                        
                        if opt
                            mean_1 = mean(r.aberration_correction.iterative_opt_zernike_coefs(1,:));
                            mean_2 = mean(r.aberration_correction.iterative_opt_zernike_coefs(2,:));
                            mean_3 = mean(r.aberration_correction.iterative_opt_zernike_coefs(3,:));
                        end
                        
                        if shack && opt
                            mean_1 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(1,:) + r.aberration_correction.iterative_opt_zernike_coefs(1,:));
                            mean_2 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(2,:) + r.aberration_correction.iterative_opt_zernike_coefs(2,:));
                            mean_3 = mean(r.aberration_correction.shack_hartmann_zernike_coefs(3,:) + r.aberration_correction.iterative_opt_zernike_coefs(3,:));
                        end
                        
                        if shack || opt
                            text = sprintf('%s\nastig 1 = %0.1f, defocus = %0.1f, astig 2 = %0.1f', text, mean_1, mean_2, mean_3);
                        end
                        
                        text = sprintf('%s\n',text);
                        
                        app.NotesTextArea.Value = [{text}; app.NotesTextArea.Value]; 
                    end
                    app.NotesTextArea.Value = [{'Rephasing found:'}; app.NotesTextArea.Value]; 
                else
                    app.rephasing_data = [];
                    app.NotesTextArea.Value = {''};
                end
            end
            
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: ImageChoiceDropDown
        function ImageChoiceDropDownValueChanged(app, event)
            value = app.ImageChoiceDropDown.Value;
            
            switch value
                case 'Power doppler'
                    app.f1EditField.Visible = true;
                    app.f1EditFieldLabel.Visible = true;
                    app.f2EditField.Visible = true;
                    app.f2EditFieldLabel.Visible = true;
                    
                    app.compositef1EditField.Visible = false;
                    app.compositef1EditFieldLabel.Visible = false;
                    app.compositef2EditField.Visible = false;
                    app.compositef2EditFieldLabel.Visible = false;
                    app.compositef3EditField.Visible = false;
                    app.compositef3EditFieldLabel.Visible = false;
                    
                    app.LowfrequencyrangeLabel.Visible = false;
                    app.HighfrequencyrangeLabel.Visible = false;
                   
                case 'Color image'
                    app.f1EditField.Visible = false;
                    app.f1EditFieldLabel.Visible = false;
                    app.f2EditField.Visible = false;
                    app.f2EditFieldLabel.Visible = false;
                    
                    app.compositef1EditField.Visible = true;
                    app.compositef1EditFieldLabel.Visible = true;
                    app.compositef2EditField.Visible = true;
                    app.compositef2EditFieldLabel.Visible = true;
                    app.compositef3EditField.Visible = true;
                    app.compositef3EditFieldLabel.Visible = true;
                    
                    app.LowfrequencyrangeLabel.Visible = true;
                    app.HighfrequencyrangeLabel.Visible = true;

                case '3D view'
                    app.f1EditField.Visible = true;
                    app.f1EditFieldLabel.Visible = true;
                    app.f2EditField.Visible = true;
                    app.f2EditFieldLabel.Visible = true;
                    
                    app.compositef1EditField.Visible = false;
                    app.compositef1EditFieldLabel.Visible = false;
                    app.compositef2EditField.Visible = false;
                    app.compositef2EditFieldLabel.Visible = false;
                    app.compositef3EditField.Visible = false;
                    app.compositef3EditFieldLabel.Visible = false;
                    
                    app.LowfrequencyrangeLabel.Visible = false;
                    app.HighfrequencyrangeLabel.Visible = false;

                    acquisition = DopplerAcquisition(app.Nx,app.Ny,app.Fs/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pix_width,app.pix_height);
                    compute_3D_view(app.FH, app.f1EditField.Value, app.f2EditField.Value, acquisition, app.gw, app.SVDCheckBox.Value, app.rephasing_data);
                otherwise
                    % unreachable
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: gwEditField
        function gwEditFieldValueChanged(app, event)
            value = app.gwEditField.Value;
            app.gw = value;
            
            if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Button pushed function: VolumeViewRenderingButton
        function VolumeViewRendering(app, event)

            % main program loop
            % Select a file and call other functions
            % to perform a full computation and create
            % output videos.
            
            if ~app.file_loaded
                return
            end
            
            % start clock to monitor
            % computation duration
            tic;
            
            % save all gui parameters to a struct.
            % The current computation will fetch every parameter
            % from the cache and not from the gui.
            % The purpose of this is to prevent gui interactions
            % to mess up the parameters while a computation is ongoing.
            app.create_cache();
            
            aberration_correction = AberrationCorrection();
            image_registration = ImageRegistration();
            
            % select parallelism policy
            switch app.cache.parallelism
                case 'CPU multithread'
                    parfor_arg = Inf;
                    use_multithread = true;
                    use_gpu = false;
                case 'GPU parallelization'
                    parfor_arg = 0;
                    use_multithread = false;
                    use_gpu = true;
                case 'CPU singlethread'
                    parfor_arg = 0;
                    use_multithread = false;
                    use_gpu = false;
                case 'CPU/GPU rendering/optim'
                    % TODO
                    parfor_arg = Inf;
                    use_multithread = true;
                    use_gpu = true;
            end
            
            % create proxy variables so that the entire gui
            % is not broadcasted to workers
            % in the parfor loops
            istream = app.interferogram_stream;
            j_win = app.cache.batch_size;
            j_step = app.cache.batch_stride;
            volume_size = app.volumesizeEditField.Value;
            local_f1 = app.cache.f1;
            local_f2 = app.cache.f2;
            local_gw = app.gw;
            local_Nx = app.Nx;
            local_Ny = app.Ny;
            local_wavelength = app.cache.wavelength;
            local_svd = app.SVDCheckBox.Value;
            local_save_additional_videos = app.cache.save_additional_videos;
            local_rephasing = app.cache.rephasing;
            local_low_memory = app.cache.low_memory;


            if local_rephasing
                local_rephasing_data = app.rephasing_data;
            else
                local_rephasing_data = [];
            end
            
            local_kernel = app.kernel;
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.cache.Fs/1000,app.cache.z,app.cache.wavelength,app.cache.DX,app.cache.DY,app.cache.pix_width,app.cache.pix_height);
            
            local_low_frequency = app.cache.low_frequency;
            local_color_f1 = app.cache.color_f1;
            local_color_f2 = app.cache.color_f2;
            local_color_f3 = app.cache.color_f3;
            
            % generate output directory
            output_dirname = create_output_directory_name(app.filepath, app.filename);
            output_dirpath = fullfile(app.filepath, output_dirname);
            mkdir(output_dirpath);
            mkdir(fullfile(output_dirpath, 'avi'));
            mkdir(fullfile(output_dirpath, 'mp4'));
            mkdir(fullfile(output_dirpath, 'png'));
            if app.saverawvideosCheckBox.Value
                mkdir(fullfile(output_dirpath, 'raw'));
            end
            
            % allocate video buffers
            num_batches = floor((app.interferogram_stream.num_frames - app.cache.batch_size) / app.cache.batch_stride);
            if local_low_memory
                [~, output_dirname] = fileparts(output_dirpath);
                output_filename = sprintf('%s_M0_tmp.%s', output_dirname, 'raw');
                fd_M0 = fopen(sprintf('%s\\%s', output_dirpath, output_filename), 'w');
            else
                
                if not(app.Nx == app.Ny)
                    video_M0 = zeros(volume_size,app.Ny,app.Nx,1,num_batches,'single');
                else
                    video_M0 = zeros(volume_size,app.Nx,app.Ny,1,num_batches,'single');
                end
                
                if local_save_additional_videos
%                     video_M1 = video_M0;
%                     video_M2 = video_M0;
%                     video_M1_over_M0 = video_M0;
%                     video_M2_over_M0 = video_M0;
%                     video_M_freq_low = video_M0;
%                     video_M_freq_high = video_M0;
%                     video_M0_pos = video_M0;
%                     video_M0_neg = video_M0;
%                     video_M1sM0r = video_M0;
                end
            end
            
            % parfor waitbar
            % we have to use a DataQueue
            % since there are no synchronization
            % primitives in matlab (mutex)
            D = parallel.pool.DataQueue;
            h = waitbar(0, '');
            afterEach(D, @update_waitbar);
            N = double(num_batches-1);
            p = 1;
                
            %% first pass - compute shifts for video registration
            registration_pass = app.cache.registration_via_phase;
            
            if registration_pass
                current_batch_idx = 1 + floor(app.positioninfileSlider.Value / (app.cache.batch_size + app.cache.batch_stride));
                shifts = compute_temporal_registration(istream, app.cache, j_win, j_step, current_batch_idx, local_gw,...
                                                       local_kernel,[], D, use_multithread);   
                % tilts zernikes used for translating an image
                zernikes = evaluate_zernikes([1 1], [1 -1], app.Nx, app.Ny);
            else
                % declare unused variables to make matlab parfor
                % loop parser happy - stupid matlab
                shifts = zeros(2, num_batches,'single');
                zernikes = zeros(app.Nx, app.Ny, 2, 'single');
            end
            
            enable_iterative_optimization = app.IterativeoptimizationCheckBox.Value;
            enable_shack_hartmann = app.ShackHarmannCheckBox.Value;
            
%            app.correction_coefs = [];
%            app.correction_zernikes = [];
%            app.previous_zernike_indices = [];
             
            if enable_shack_hartmann
                if ~isempty(app.correction_coefs) %not amazing because doesn't work for changed parameters
                else
                % aberration computation pass
                %
                % Implementation - Shack Hartmann simulation
                
                % shack hartmann params
                zernike_ranks = app.cache.shack_hartmann_zernike_ranks;
                num_subapertures = app.cache.num_subapertures;
                subaperture_margin = app.cache.subaperture_margin;
                
                calibration_factor = 20;
                corrmap_margin = 0.4;
                
                power_filter_corrector = 1;
                sigma_filter_corrector = 1;
                
                % select subapertures to exclude, depending on the number
                % of subaperture used. This is chosen experimentally, the
                % value of the number of subapertures is constrained in the
                % GUI so the following switch should always branch to a
                % valid choice of excluded_subapertures
                switch num_subapertures
                    case 4
                        excluded_subapertures = [1; 4; 13; 16];
                    case 5
                        excluded_subapertures = [1; 5; 21; 25];
                    case 6
                        excluded_subapertures = [1; 2; 5; 6; 7; 12; 25; 30; 31; 32; 35; 36];
                    case 7
                        excluded_subapertures = [1; 2; 6; 7; 8; 14; 36; 42; 43; 44; 48; 49];
                    case 8
                        excluded_subapertures = [1; 2; 7; 8; 9; 16; 49; 56; 57; 58; 63; 64];
                    otherwise
                        error('Unreachable code was reached. Check value of num_subapertures');
                end
                
                % perform iterative aberration computation with shack-hartmann
                for r = 2:zernike_ranks
                    send(D, 3); % reset progress bar
                    
                    % r-th zernike line has r+1 zernikes in it
                    cur_num_zernikes = r + 1;
                    
                    
                    % compute indices of the zernikes of current rank
                    current_zernike_indices = (cur_num_zernikes * (cur_num_zernikes - 1) / 2):(cur_num_zernikes * (cur_num_zernikes - 1) / 2 + cur_num_zernikes - 1);
                    
                    % apply previous aberration correction
                    [current_correction_coefs, stiched_moments_video] = compute_correction_shack_hartmann(istream,app.cache,local_kernel,local_rephasing_data,app.gw,[],D,use_gpu,use_multithread,current_zernike_indices,...
                                                                     shifts,num_subapertures,calibration_factor,subaperture_margin,corrmap_margin,...
                                                                     excluded_subapertures,power_filter_corrector,sigma_filter_corrector,...
                                                                    app.previous_zernike_indices, app.correction_coefs);
                    [~, current_correction_zernikes] = zernike_phase(current_zernike_indices, app.Nx, app.Ny);
                                                                 
                   app.correction_coefs = [app.correction_coefs; current_correction_coefs];
                   app.correction_zernikes = cat(3,app.correction_zernikes, current_correction_zernikes);
                   app.previous_zernike_indices = [app.previous_zernike_indices, current_zernike_indices];
                end

                end
                % save correction coefs and indices for future export
                aberration_correction.shack_hartmann_zernike_indices =app.previous_zernike_indices;
                aberration_correction.shack_hartmann_zernike_coefs =app.correction_coefs;
            end
%             
%             if enable_iterative_optimization
%                 % aberration computation pass
%                 %
%                 % Implementation - Entropy optimization
%                 %
%                 % here we generate a set of zernike coefficients
%                 % that represent the aberation present on the
%                 % reconstructed images
%                 
%                 % compute coefficients for high order zernikes
%                 % we make a different optimization pass for each
%                 % additional zernike rank
%                 
%                 % reset correction indices and coefs arrays that ay have
%                 % been set by the shack hartmann pass
%                 zernike_indices = [];
%                app.correction_coefs = [];
%                 
%                 zernike_ranks = app.optimizationzernikeranksEditField.Value;
%                 for r = 2:zernike_ranks
%                     send(D, 4); % reset progress bar
%                     num_zernikes = r + 1; % map to zernfun zernike ordering
%                     
%                     % construct high order zernike indices
%                     current_zernike_indices = (num_zernikes * (num_zernikes - 1) / 2):(num_zernikes * (num_zernikes - 1) / 2 + num_zernikes - 1);
%                     
%                     % compute correction
%                     current_cor = compute_correction(istream,app.cache,local_kernel,local_rephasing_data,app.gw,[],D,use_gpu,use_multithread,current_zernike_indices,app.cache.zernikes_tol,app.cache.mask_num_iter,...
%                                                      app.cache.max_constraint,shifts,app.previous_zernike_indices,app.correction_coefs);
%                     [~, current_correction_zernikes] = zernike_phase(current_zernike_indices, app.Nx, app.Ny);
%                     
%                     % append coefs to correction coefs array
%                    app.correction_coefs = [correction_coefs; current_cor];
%                    app.correction_zernikes = cat(3,app.correction_zernikes, current_correction_zernikes);
%                    app.previous_zernike_indices = [app.previous_zernike_indices current_zernike_indices];
%                     zernike_indices = [zernike_indices current_zernike_indices];
%                 end
%                 
%                 % save correction coefs and indices for future export
%                 aberration_correction.iterative_opt_zernike_indices = zernike_indices;
%                 aberration_correction.iterative_opt_zernike_coefs =app.correction_coefs;
%             end
%             
%             if ~enable_shack_hartmann && ~enable_iterative_optimization % no aberration compensation at all
%                 % define dummy unused variables with good size
%                 % to make matlab parfor parser happy - stupid matlab
%                app.correction_coefs = zeros(3, istream.num_frames, 'single');
%                app.correction_zernikes = zeros(app.Nx, app.Ny, 3, 'single');
%             end
%            
            [rephasing_zernikes, shack_zernikes, iterative_zernikes] = aberration_correction.generate_zernikes(app.Nx, app.Ny);
%             
            %% Final pass
            % Here we apply all transfromations computed in previous passes
            % and construct the videos
            
            % There are two ways of doing this, the first one is a 'low
            % memory' mode in which the reconstructed data is cached on a
            % file on the disk to avoid allocating massive video buffers.
            % This is useful when the batch stride is so small that the
            % whole video buffer would overflow memory.
            %
            % The second one is a more straightforward mode in which whole
            % video buffers are allocated and stored in RAM. This is the
            % default and most simple mode
            if local_low_memory
                reg_frame_batch = app.interferogram_stream.read_frame_batch(app.refbatchsizeEditField.Value, floor(app.positioninfileSlider.Value));
                reg_FH = fftshift(fft2(reg_frame_batch)) .* app.kernel;
                reg_FH = rephase_FH(reg_FH,local_rephasing_data,app.refbatchsizeEditField.Value,floor(app.positioninfileSlider.Value));
                acquisition = DopplerAcquisition(app.Nx,app.Ny,app.Fs/1000,app.cache.z,app.cache.wavelength,app.cache.DX,app.cache.DY,app.pix_width,app.pix_height);
                reg_hologram = reconstruct_hologram(reg_FH, app.cache.f1, app.cache.f2, acquisition, app.gw, use_gpu, app.SVDCheckBox.Value);
                
                block_size = 1024; % TODO: this should stay hardcoded but to a higher value
                block_M0 = zeros(app.Nx,app.Ny,1,block_size);
                send(D,-2); % display 'video construction' on progress bar
                for batch_idx = 1:block_size:num_batches
                    last_iter = min(batch_idx - 1 + block_size, num_batches) - batch_idx + 1;
                    parfor (inner_idx = 1:last_iter, parfor_arg)
                        frame_batch = istream.read_frame_batch(j_win, (batch_idx - 1 + inner_idx - 1) * j_step);
                        FH = compute_FH_from_frame_batch(frame_batch, local_kernel);
                        
                        if local_rephasing
                            FH = rephase_FH(FH, local_rephasing_data, j_win, (batch_idx - 1 + inner_idx - 1) * j_step);
                        end
                        
                        % add tilted phase for FH registration
                        if registration_pass
                            FH = register_FH(FH, shifts(:, batch_idx:(batch_idx+1)-1), j_win, 1);
                        end
                        
                        if enable_iterative_optimization || enable_shack_hartmann
                            phase = aberration_correction.compute_total_phase(batch_idx,rephasing_zernikes,shack_zernikes,iterative_zernikes);
                            correction = exp(-1i * phase);
                            
                            % apply correction
                            FH = FH .* correction;
                        end
        
                        M0 = reconstruct_hologram(FH, local_f1, local_f2, acquisition, local_gw, false, local_svd, []);
                        
                        % save frame to block buffer
                        block_M0(:,:,:,inner_idx) = gather(M0);
                        
                        send(D, 0);
                    end
                    
                    if app.cache.registration
                        [block_M0, block_shifts] = register_video_from_reference(block_M0(:,:,:,1:last_iter),reg_hologram);   
                        shifts(:,batch_idx:batch_idx+last_iter-1) = block_shifts;
                    end
                    
                    % write block to file
                    fwrite(fd_M0,block_M0(:),'single');
                end
                fclose(fd_M0);
                
                if app.cache.registration
                    % save shifts for export
                    image_registration.translation_x = shifts(1,:);
                    image_registration.translation_y = shifts(2,:);
                end
                
                export_raw = app.saverawvideosCheckBox.Value;
                generate_video_low_memory(sprintf('%s\\%s', output_dirpath, output_filename), app.Nx, app.Ny, 1, num_batches, output_dirpath, 'M0', 0.0005, app.cache.temporal_filter, local_low_frequency, export_raw, true);
            else % ~local_low_memory
                send(D,-2); % display 'video construction' on progress bar
                parfor (batch_idx = 1:num_batches, parfor_arg) 
                    frame_batch = istream.read_frame_batch(j_win, (batch_idx - 1) * j_step);
                    FH = compute_FH_from_frame_batch(frame_batch, local_kernel);

                    if local_rephasing
                        FH = rephase_FH(FH, local_rephasing_data, j_win, (batch_idx - 1) * j_step);
                    end

                    % add tilted phase for FH registration
                    if registration_pass
                        FH = register_FH(FH, shifts(:, batch_idx:(batch_idx+1)-1), j_win, 1);
                    end

                    if enable_iterative_optimization || enable_shack_hartmann
                        phase = aberration_correction.compute_total_phase(batch_idx,rephasing_zernikes,shack_zernikes,iterative_zernikes);
                        correction = exp(-1i * phase);

                        % apply correction
                        FH = FH .* correction;
                    end

                    if local_save_additional_videos
                        %                         [M0, sqrt_M0, M1, M2, M_freq_low, M_freq_high, M0_pos, M0_neg, M1sM0r, velocity] = reconstruct_hologram_extra(FH, local_wavelength, local_f1, local_f2, acquisition, local_gw, false, local_svd, [],...
                        %                                                                                           local_color_f1, local_color_f2, local_color_f3);
                        %                         video_M0(:,:,:,batch_idx) = gather(M0);
                        %                         video_M1(:,:,:,batch_idx) = gather(M1);
                        %                         video_M2(:,:,:,batch_idx) = gather(M2);
                        %                         video_M1_over_M0(:,:,:,batch_idx) = gather(M1) ./ gather(M0);
                        %                         video_M2_over_M0(:,:,:,batch_idx) = gather(M2) ./ gather(sqrt_M0);
                        %                         video_M_freq_low(:,:,:,batch_idx) = gather(M_freq_low);
                        %                         video_M_freq_high(:,:,:,batch_idx) = gather(M_freq_high);
                        %                         video_M0_pos(:,:,:,batch_idx) = gather(M0_pos);
                        %                         video_M0_neg(:,:,:,batch_idx) = gather(M0_neg);
                        %                         video_M1sM0r(:,:,:,batch_idx) = gather(M1sM0r);
                        %                         video_velocity(:,:,:,batch_idx) = gather(velocity);
                    else
                        M0 = reconstruct_holograms_stack(FH, local_f1, local_f2, acquisition, local_gw, false, local_svd, [], volume_size);
                        M0 = permute(M0, [3 1 2]);
                        video_M0(:,:,:,1,batch_idx) = M0;

                    end
                    send(D, 0);

                end
%                 figure(1)
%                 imagesc(video_M0(:,:,256,1,1))
%                 if local_save_additional_videos
%                     % generate color video
%                     if local_low_frequency
%                         video_color = construct_colored_video(video_M_freq_high, video_M_freq_low);
%                     else
%                         video_color = construct_colored_video(video_M_freq_low, video_M_freq_high);
%                     end
                    
                    % generate directional video
                    
%                end

                    fourth_y = floor(local_Ny/4);
                    middle_x = floor(local_Nx/2);
                    vid_M0_xz = mean(video_M0(:,:,fourth_y : 3*fourth_y,:,:),3);
                    vid_M0_xz = reshape(vid_M0_xz, [volume_size,local_Nx,1,num_batches]);
                    vid_M0_yz = video_M0(:,middle_x,:,:,:);
                    vid_M0_yz = reshape(vid_M0_yz, [volume_size,local_Ny,1,num_batches]);
                    
                    if app.cache.registration
                    % post reconstruction image registration.
                    % This registration is performed on the final video,
                    % it does not use tilts zernikes to perform the
                    % registration.
                    
                    % construct reference image
                    reg_frame_batch = app.interferogram_stream.read_frame_batch(app.refbatchsizeEditField.Value, floor(app.positioninfileSlider.Value));
                    reg_FH = fftshift(fft2(reg_frame_batch)) .* app.kernel;
                    reg_FH = rephase_FH(reg_FH,local_rephasing_data,app.refbatchsizeEditField.Value,floor(app.positioninfileSlider.Value));
                    acquisition = DopplerAcquisition(app.Nx,app.Ny,app.Fs/1000,app.cache.z,app.cache.wavelength,app.cache.DX,app.cache.DY,app.pix_width,app.pix_height);
                    reg_hologram = reconstruct_holograms_stack(reg_FH, app.cache.f1, app.cache.f2, acquisition, app.gw, use_gpu, app.SVDCheckBox.Value, [], volume_size);

                    reg_hologram = permute(reg_hologram, [3 1 2 ]);
                    [vid_M0_xz, shifts_xz] = register_video_from_reference(vid_M0_xz, mean(reg_hologram(:,:,fourth_y:fourth_y*3),3));
                    [vid_M0_yz, shifts_yz] = register_video_from_reference(vid_M0_yz, squeeze(reg_hologram(:,middle_x,:)));

                    for i = 1 : num_batches %for now we only correct z dimension movement
                        video_M0(:,:,:,:,i) = circshift(video_M0(:,:,:,:,i), floor(shifts_xz(1,i)), 1);
                        video_M0(:,:,:,:,i) = circshift(video_M0(:,:,:,:,i), floor(shifts_xz(2,i)), 2);
                        video_M0(:,:,:,:,i) = circshift(video_M0(:,:,:,:,i), floor(shifts_yz(2,i)), 3);
                    end
                    
                    app.OCTstack = video_M0; % we pass the stack to the app memory
                    
%                     if local_save_additional_videos
%                         video_M1 = register_video_from_shifts(video_M1, shifts);
%                         video_M2 = register_video_from_shifts(video_M2, shifts);
%                         video_M1_over_M0 = register_video_from_shifts(video_M1_over_M0, shifts);
%                         video_M2_over_M0 = register_video_from_shifts(video_M2_over_M0, shifts);
%                         video_color = register_video_from_shifts(video_color, shifts);
%                         
%                         video_M_freq_high = register_video_from_shifts(video_M_freq_high, shifts);
%                         video_M_freq_low = register_video_from_shifts(video_M_freq_low, shifts);
%                         
%                         video_M0_pos = register_video_from_shifts(video_M0_pos, shifts);
%                         video_M0_neg = register_video_from_shifts(video_M0_neg, shifts);
%                         
%                         video_M1sM0r = register_video_from_shifts(video_M1sM0r, shifts);
%                         
%                         video_velocity = register_video_from_shifts(video_velocity, shifts);
%                     end
%                     
%                     % save shifts for export
                    image_registration.translation_x = shifts(1,:);
                    image_registration.translation_y = shifts(2,:);
                end
                
%                 if local_save_additional_videos
%                     video_directional = construct_directional_video(video_M0_pos, video_M0_neg, app.cache.temporal_filter);
%                     video_fmean = construct_fmean_video(video_M1sM0r, video_M0, app.cache.temporal_filter);
%                 end
               
              export_raw = app.saverawvideosCheckBox.Value;
              %vid_M0 = zeros(100,512,1,13);
              vid_M0 = video_M0(:,:,1,:,:);
              vid_M0 = reshape(vid_M0, [volume_size,local_Nx,1,num_batches]);
              generate_video(vid_M0, output_dirpath, 'M0', 0.0005, app.cache.temporal_filter, local_low_frequency, export_raw, true);
%                 if local_save_additional_videos
%                     generate_video(video_M1, output_dirpath, 'DopplerAVG', 0.0005, app.cache.temporal_filter, local_low_frequency, export_raw, true);
%                     generate_video(video_M2, output_dirpath, 'DopplerRMS', 0.0005, app.cache.temporal_filter, local_low_frequency, export_raw, true);
%                     generate_video(video_M1_over_M0, output_dirpath, 'NormalizedDopplerAVG', 0.0005, app.cache.temporal_filter, local_low_frequency, export_raw, true);
%                     generate_video(video_M2_over_M0, output_dirpath, 'NormalizedDopplerRMS', 0.0005, app.cache.temporal_filter, local_low_frequency, export_raw, true);
%                     
%                     % no contrast enhancement for color video, it's already
%                     % been done previously
%                     generate_video(video_color, output_dirpath, 'Color', [], app.cache.temporal_filter, local_low_frequency, export_raw, false);
%                     generate_video(video_directional, output_dirpath, 'Directional', [], [], false, export_raw, true);
%                     generate_video(video_fmean, output_dirpath, 'Fmean', [], [], false, export_raw, true);
%                     generate_video(video_M0_pos, output_dirpath, 'M0pos', [], app.cache.temporal_filter, local_low_frequency, export_raw, true);
%                     generate_video(video_M0_neg, output_dirpath, 'M0neg', [], app.cache.temporal_filter, local_low_frequency, export_raw, true);
%                     generate_video(video_velocity, output_dirpath, 'Velocity', [], app.cache.temporal_filter, local_low_frequency, export_raw, true);
%                     
%                     if enable_shack_hartmann
%                         % phase video
%                         video_phase = correction_phase_video(aberration_correction, app.Nx, app.Ny);
%                         generate_video(video_phase, output_dirpath, 'Phase', [], [], false, false, false);
%                         generate_video(stiched_moments_video, output_dirpath, 'StichedMoments', [], [], false, false, false);
%                     end
%                 
%                     % generate additional images
%                     if local_low_frequency
%                         color_img = construct_colored_image(video_M_freq_high, video_M_freq_low, true);
%                     else
%                         color_img = construct_colored_image(video_M_freq_low, video_M_freq_high, false);
%                     end
%                     
%                     RI = construct_resistivity_index(video_M0);
%                     
%                     color_img = flip(color_img);
%                     RI = flip(RI);
%                     
%                     color_output_filename = sprintf('%s_%s.%s', output_dirname, 'Color', 'png');
%                     RI_output_filename = sprintf('%s_%s.%s', output_dirname, 'Resistivity', 'png');
%                     imwrite(color_img, sprintf('%s\\png\\%s', output_dirpath, color_output_filename));
%                     imwrite(RI, sprintf('%s\\png\\%s', output_dirpath, RI_output_filename));
%                     
%                     % ppt presentation
%                     generate_ppt(output_dirname, output_dirpath, app.cache.Fs, app.cache.f1, app.cache.f2, fullfile(output_dirpath, output_dirname));
%                 end
           
            end % local_low_memory      
            
            function update_waitbar(sig)
                % signal table
                % 0 => increment value
                % 1 => reset for stage 1 (registration)
                % 2 => reset for stage 2 (video_M0 computation)
                switch sig
                    case 0
                        waitbar(p/N, h);
                        p = p + 1;
                    case -1
                        waitbar(0,h,'Translation registration...');
                        p = 1;
                    case -2
                        waitbar(0,h,'Image rendering...');
                        p = 1;
                    case 1
                        waitbar(0,h,'Optimizing defocus...');
                        p = 1;
                    case 2
                        waitbar(0,h,'Optimizing astigmatism...');
                        p = 1;
                    case 3
                        waitbar(0,h,'Shack-Hartmann...')
                        p = 1;
                    otherwise
                        waitbar(0,h,'Iterative optimization...');
                        p = 1;
                end

            % OCT data display
            zPosition = app.depthpositionEditField.Value;S
            app.hologram = mean(squeeze(app.OCTstack(zPosition : zPosition + app.depthrangeEditField.Value,:,:,:,1)), 1)
            app.show_hologram();
            
            imshow(mat2gray(squeeze(app.OCTstack(:,:,fourth_x,:,1))), 'Parent', app.UIAxes_3,...
                'XData', [1 app.UIAxes_3.Position(3)], ...
                'YData', [1 app.UIAxes_3.Position(4)]);


            end
            
            % export shifts to AberrationCorrection struct as zernikes
%             if registration_pass || app.cache.registration
%                 magic_number = 710;
%                 aberration_correction.rephasing_zernike_indices = [1 2];
%                 aberration_correction.rephasing_zernike_coefs = [shifts(1,:)/local_Nx; shifts(2,:)/local_Ny] * magic_number * 2;
%             end
            
            % add computed correction to rephasing data
%             new_rephasing_data = RephasingData(app.cache.batch_size, app.cache.batch_stride, aberration_correction);
%             % update rephasing array
%             rephasing_data = [local_rephasing_data new_rephasing_data];

            % export patient informations to text file_M0
%             txt_filename = sprintf('%s.txt', output_dirname);
%             save_string_cells_to_file(fullfile(output_dirpath, txt_filename), app.cache.notes);

            % export some workspace variables to .mat file
%             mat_filename = sprintf('%s.mat', output_dirname);
%             cache = app.cache;
%             save(fullfile(output_dirpath, mat_filename), 'cache');
%             
%             save(fullfile(output_dirpath, mat_filename), 'aberration_correction', '-append');
%             save(fullfile(output_dirpath, mat_filename), 'image_registration', '-append');
            
%             if app.cache.rephasing
%                 save(fullfile(output_dirpath, mat_filename), 'rephasing_data', '-append');
%             end
            
            % close progress bar window
            close(h);
            
            % display elapsed time
            toc;
        end

        % Value changed function: volumesizeEditField
        function VolumeSizeValueChanged(app, event)
            value = app.volumesizeEditField.Value;
            
        end

        % Value changed function: depthpositionEditField
        function DepthPosition(app, event)
            zPosition = app.depthpositionEditField.Value;
            
            meanSlice = mean(app.OCTstack(zPosition : zPosition + app.depthrangeEditField.Value,:,:,:,1), 1);

            app.hologram = squeeze(meanSlice);

            app.show_hologram();
            
            img = insertShape((mat2gray (squeeze(app.OCTstack(:,:,100,:,1)))),'Line',[1 zPosition 512 zPosition],'LineWidth',2,'Color','blue');
            img = insertShape(img, 'Line',[1 zPosition + app.depthrangeEditField.Value 512 zPosition + app.depthrangeEditField.Value],'LineWidth',2,'Color','blue');
            imshow(img, 'Parent', app.UIAxes_3,...
                'XData', [1 app.UIAxes_3.Position(3)], ...
                'YData', [1 app.UIAxes_3.Position(4)]); % need to change x pos

        end

        % Value changed function: depthrangeEditField
        function DepthRange(app, event)
            value = app.depthrangeEditField.Value;
            zPosition = app.depthpositionEditField.Value;
            
            meanSlice = mean(app.OCTstack(zPosition : zPosition + value,:,:,:,1), 1);

            app.hologram = squeeze(meanSlice);

            app.show_hologram();
            
            img = insertShape((mat2gray (squeeze(app.OCTstack(:,:,100,:,1)))),'Line',[1 zPosition 512 zPosition],'LineWidth',2,'Color','blue');
            img = insertShape(img, 'Line',[1 zPosition + value 512 zPosition + value],'LineWidth',2,'Color','blue');
            imshow(img, 'Parent', app.UIAxes_3,...
                'XData', [1 app.UIAxes_3.Position(3)], ...
                'YData', [1 app.UIAxes_3.Position(4)]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LaserDopplerHolographyUIFigure = uifigure('Visible', 'off');
            app.LaserDopplerHolographyUIFigure.Name = 'Laser Doppler Holography';
            app.LaserDopplerHolographyUIFigure.CloseRequestFcn = createCallbackFcn(app, @LaserDopplerHolographyUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.LaserDopplerHolographyUIFigure);
            app.MainviewTab = uitab(app.TabGroup);
            app.MainviewTab.Title = 'Main view';
            app.FileselectionPanel = uipanel(app.MainviewTab);
            app.FileselectionPanel.Tooltip = {'Select a reconstruction algorithm that minimizes memory footprint during post processing'};
            app.FileselectionPanel.Title = 'File selection';
            app.LoadfileButton = uibutton(app.FileselectionPanel, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.ImagerenderingButton = uibutton(app.FileselectionPanel, 'push');
            app.ImagerenderingButton.ButtonPushedFcn = createCallbackFcn(app, @ImagerenderingButtonPushed, true);
            app.ImagerenderingButton.Text = 'Image rendering';
            app.batchsizeEditFieldLabel = uilabel(app.FileselectionPanel);
            app.batchsizeEditFieldLabel.Text = 'batch size';
            app.batchsizeEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.batchsizeEditField.Limits = [1 Inf];
            app.batchsizeEditField.ValueChangedFcn = createCallbackFcn(app, @batchsizeEditFieldValueChanged, true);
            app.batchsizeEditField.Tooltip = {'Number of interferograms used to generate a single image. Increase value for better video quality.'};
            app.batchsizeEditField.Value = 512;
            app.batchstrideEditFieldLabel = uilabel(app.FileselectionPanel);
            app.batchstrideEditFieldLabel.Text = 'batch stride';
            app.batchstrideEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.batchstrideEditField.Limits = [1 Inf];
            app.batchstrideEditField.Tooltip = {'Number of interferograms to skip between two images. Increase for a shorter video.'};
            app.batchstrideEditField.Value = 1024;
            app.zEditFieldLabel = uilabel(app.FileselectionPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.zEditField.Limits = [0 Inf];
            app.zEditField.ValueChangedFcn = createCallbackFcn(app, @zEditFieldValueChanged, true);
            app.zEditField.Tooltip = {'Reconstruction distance in mm. Can be computed automatically with the ''Autofocus'' button.'};
            app.zEditField.Value = 0.22;
            app.wavelengthEditFieldLabel = uilabel(app.FileselectionPanel);
            app.wavelengthEditFieldLabel.Text = 'wavelength';
            app.wavelengthEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.wavelengthEditField.Limits = [0 Inf];
            app.wavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @wavelengthEditFieldValueChanged, true);
            app.wavelengthEditField.Tooltip = {'Laser wavelength in nanometers'};
            app.wavelengthEditField.Value = 7.85e-07;
            app.imageregistrationCheckBox = uicheckbox(app.FileselectionPanel);
            app.imageregistrationCheckBox.Text = ' image registration';
            app.imageregistrationCheckBox.Value = true;
            app.temporalfilterCheckBox = uicheckbox(app.FileselectionPanel);
            app.temporalfilterCheckBox.Text = 'temporal filter';
            app.temporalfilterCheckBox.Value = true;
            app.ParallelismDropDown = uidropdown(app.FileselectionPanel);
            app.ParallelismDropDown.Items = {'CPU multithread', 'GPU parallelization', 'CPU singlethread', 'CPU/GPU rendering/optim', ''};
            app.ParallelismDropDown.Value = 'CPU multithread';
            app.positioninfileSliderLabel = uilabel(app.FileselectionPanel);
            app.positioninfileSliderLabel.Text = {'position in file'; ''};
            app.positioninfileSlider = uislider(app.FileselectionPanel);
            app.positioninfileSlider.Limits = [0 1];
            app.positioninfileSlider.MajorTicks = [];
            app.positioninfileSlider.ValueChangedFcn = createCallbackFcn(app, @positioninfileSliderValueChanged, true);
            app.positioninfileSlider.MinorTicks = [];
            app.positioninfileSlider.Tooltip = {'Change value to display a different video timestamp in the gui.'};
            app.temporalfilterEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.temporalfilterEditField.Limits = [1 Inf];
            app.temporalfilterEditField.Tooltip = {'Laser wavelength in nanometers'};
            app.temporalfilterEditField.Value = 2;
            app.saverawvideosCheckBox = uicheckbox(app.FileselectionPanel);
            app.saverawvideosCheckBox.Text = 'save raw videos';
            app.AutofocusButton = uibutton(app.FileselectionPanel, 'push');
            app.AutofocusButton.ButtonPushedFcn = createCallbackFcn(app, @AutofocusButtonPushed, true);
            app.AutofocusButton.Text = 'Autofocus';
            app.SVDCheckBox = uicheckbox(app.FileselectionPanel);
            app.SVDCheckBox.ValueChangedFcn = createCallbackFcn(app, @SVDCheckBoxValueChanged, true);
            app.SVDCheckBox.Tooltip = {'Enable SVD hologram filtering in hologram construction'};
            app.SVDCheckBox.Text = 'SVD';
            app.SVDCheckBox.Value = true;
            app.phaseregistrationCheckBox = uicheckbox(app.FileselectionPanel);
            app.phaseregistrationCheckBox.Visible = 'off';
            app.phaseregistrationCheckBox.Tooltip = {'Activates video registration. Check this if the video is too shaky.'};
            app.phaseregistrationCheckBox.Text = 'phase registration';
            app.lowfrequencyCheckBox = uicheckbox(app.FileselectionPanel);
            app.lowfrequencyCheckBox.ValueChangedFcn = createCallbackFcn(app, @lowfrequencyCheckBoxValueChanged, true);
            app.lowfrequencyCheckBox.Text = 'low frequency';
            app.DxEditFieldLabel = uilabel(app.FileselectionPanel);
            app.DxEditFieldLabel.Text = 'Dx';
            app.DxEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.DxEditField.Limits = [-1 1];
            app.DxEditField.ValueChangedFcn = createCallbackFcn(app, @DxEditFieldValueChanged, true);
            app.DxEditField.Tooltip = {''};
            app.DyEditFieldLabel = uilabel(app.FileselectionPanel);
            app.DyEditFieldLabel.Text = 'Dy';
            app.DyEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.DyEditField.Limits = [-1 1];
            app.DyEditField.ValueChangedFcn = createCallbackFcn(app, @DyEditFieldValueChanged, true);
            app.DyEditField.Tooltip = {''};
            app.EditFieldLabel = uilabel(app.FileselectionPanel);
            app.EditFieldLabel.Text = '';
            app.EditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Tooltip = {''};
            app.refbatchsizeEditFieldLabel = uilabel(app.FileselectionPanel);
            app.refbatchsizeEditFieldLabel.Text = 'ref batch size';
            app.refbatchsizeEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.refbatchsizeEditField.Limits = [1 Inf];
            app.refbatchsizeEditField.Tooltip = {'Number of interferograms used to generate a single image. Increase value for better video quality.'};
            app.refbatchsizeEditField.Value = 512;
            app.saveadditionalvideosCheckBox = uicheckbox(app.FileselectionPanel);
            app.saveadditionalvideosCheckBox.Text = 'save additional videos';
            app.saveadditionalvideosCheckBox.Value = true;
            app.rephasingCheckBox = uicheckbox(app.FileselectionPanel);
            app.rephasingCheckBox.ValueChangedFcn = createCallbackFcn(app, @rephasingCheckBoxValueChanged, true);
            app.rephasingCheckBox.Text = 'rephasing';
            app.rephasingCheckBox.Value = true;
            app.lowmemoryCheckBox = uicheckbox(app.FileselectionPanel);
            app.lowmemoryCheckBox.Text = 'low memory';
            app.fsEditFieldLabel = uilabel(app.FileselectionPanel);
            app.fsEditFieldLabel.Text = 'fs';
            app.fsEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.fsEditField.Limits = [0 Inf];
            app.fsEditField.Editable = 'off';
            app.fsEditField.Tooltip = {'Sampling frequency (kHz)'};
            app.fsEditField.Value = 75;
            app.CurrentFilePanel = uipanel(app.MainviewTab);
            app.CurrentFilePanel.Tooltip = {''};
            app.CurrentFilePanel.Title = 'Current file';
            app.GridLayout = uigridlayout(app.CurrentFilePanel);
            app.UIAxes_3 = uiaxes(app.GridLayout);
            app.UIAxes_3.CLim = [0 1];
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_1 = uiaxes(app.GridLayout);
            app.UIAxes_1.CLim = [0 1];
            app.UIAxes_1.XTick = [];
            app.UIAxes_1.YTick = [];
            app.AberrationcompensationPanel = uipanel(app.GridLayout);
            app.AberrationcompensationPanel.Title = 'Aberration compensation';
            app.Label_2 = uilabel(app.AberrationcompensationPanel);
            app.Label_2.Text = {''; ''};
            app.ShackHarmannCheckBox = uicheckbox(app.AberrationcompensationPanel);
            app.ShackHarmannCheckBox.Tooltip = {'Perform aberration compensation with Shack Hartmann simulation'};
            app.ShackHarmannCheckBox.Text = 'Shack Harmann';
            app.zernikeranksEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.zernikeranksEditFieldLabel.Text = 'zernike ranks';
            app.shackhartmannzernikeranksEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.shackhartmannzernikeranksEditField.Limits = [2 5];
            app.shackhartmannzernikeranksEditField.RoundFractionalValues = 'on';
            app.shackhartmannzernikeranksEditField.Tooltip = {'Number of zernike ranks to use for aberration correction'};
            app.shackhartmannzernikeranksEditField.Value = 2;
            app.subaperturesEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.subaperturesEditFieldLabel.Text = 'subapertures';
            app.subaperturesEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.subaperturesEditField.Limits = [4 8];
            app.subaperturesEditField.RoundFractionalValues = 'on';
            app.subaperturesEditField.Tooltip = {'Number of subapertures used for Shack-Hartmann simulation'};
            app.subaperturesEditField.Value = 5;
            app.subaperturemarginEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.subaperturemarginEditFieldLabel.Text = 'subaperture margin';
            app.subaperturemarginEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.subaperturemarginEditField.Limits = [0 Inf];
            app.subaperturemarginEditField.Tooltip = {'Number of subapertures used for Shack-Hartmann simulation'};
            app.subaperturemarginEditField.Value = 0.3;
            app.PreviewButton = uibutton(app.AberrationcompensationPanel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.IterativeoptimizationCheckBox = uicheckbox(app.AberrationcompensationPanel);
            app.IterativeoptimizationCheckBox.Tooltip = {'Activate aberration compensation via image entropy optimization. This might significantly increase the computation time.'};
            app.IterativeoptimizationCheckBox.Text = 'Iterative optimization';
            app.zernikeranksEditField_2Label = uilabel(app.AberrationcompensationPanel);
            app.zernikeranksEditField_2Label.Text = 'zernike ranks';
            app.optimizationzernikeranksEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.optimizationzernikeranksEditField.Limits = [2 Inf];
            app.optimizationzernikeranksEditField.Tooltip = {'Number of zernike ranks that constitue the aberration projection polynomial space'};
            app.optimizationzernikeranksEditField.Value = 2;
            app.toleranceLabel = uilabel(app.AberrationcompensationPanel);
            app.toleranceLabel.Text = 'tolerance';
            app.zernikestolEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.zernikestolEditField.Limits = [0 Inf];
            app.zernikestolEditField.Tooltip = {''};
            app.zernikestolEditField.Value = 0.05;
            app.maxconstraintLabel = uilabel(app.AberrationcompensationPanel);
            app.maxconstraintLabel.Text = 'max constraint';
            app.maxconstraintEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.maxconstraintEditField.Limits = [1 Inf];
            app.maxconstraintEditField.RoundFractionalValues = 'on';
            app.maxconstraintEditField.Tooltip = {''};
            app.maxconstraintEditField.Value = 10;
            app.masknumiterLabel = uilabel(app.AberrationcompensationPanel);
            app.masknumiterLabel.Text = 'mask num iter';
            app.masknumiterEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.masknumiterEditField.Limits = [1 Inf];
            app.masknumiterEditField.RoundFractionalValues = 'on';
            app.masknumiterEditField.Tooltip = {''};
            app.masknumiterEditField.Value = 1;
            app.PreviewLabel = uilabel(app.AberrationcompensationPanel);
            app.PreviewLabel.Text = {'astig_1 : 0.0'; 'defocus: 0.0'; 'astig_2 : 0.0'};
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ImagerenderingPanel = uipanel(app.GridLayout3);
            app.ImagerenderingPanel.Title = 'Image rendering';
            app.f1EditFieldLabel = uilabel(app.ImagerenderingPanel);
            app.f1EditFieldLabel.Text = 'f1';
            app.f1EditField = uieditfield(app.ImagerenderingPanel, 'numeric');
            app.f1EditField.Limits = [0 Inf];
            app.f1EditField.ValueChangedFcn = createCallbackFcn(app, @f1EditFieldValueChanged, true);
            app.f1EditField.Tooltip = {'Frequency lower integration bound in kHz.'};
            app.f1EditField.Value = 8;
            app.f2EditFieldLabel = uilabel(app.ImagerenderingPanel);
            app.f2EditFieldLabel.Text = 'f2';
            app.f2EditField = uieditfield(app.ImagerenderingPanel, 'numeric');
            app.f2EditField.Limits = [0 Inf];
            app.f2EditField.ValueChangedFcn = createCallbackFcn(app, @f2EditFieldValueChanged, true);
            app.f2EditField.Tooltip = {'Frequency upper integration bound in kHz.'};
            app.f2EditField.Value = 30;
            app.LowfrequencyrangeLabel = uilabel(app.ImagerenderingPanel);
            app.LowfrequencyrangeLabel.Visible = 'off';
            app.LowfrequencyrangeLabel.Text = 'Low frequency range: 1..6';
            app.HighfrequencyrangeLabel = uilabel(app.ImagerenderingPanel);
            app.HighfrequencyrangeLabel.Visible = 'off';
            app.HighfrequencyrangeLabel.Text = 'High frequency range: 6..30';
            app.compositef1EditFieldLabel = uilabel(app.ImagerenderingPanel);
            app.compositef1EditFieldLabel.Visible = 'off';
            app.compositef1EditFieldLabel.Text = 'f1';
            app.compositef1EditField = uieditfield(app.ImagerenderingPanel, 'numeric');
            app.compositef1EditField.Limits = [0 Inf];
            app.compositef1EditField.ValueChangedFcn = createCallbackFcn(app, @compositef1EditFieldValueChanged, true);
            app.compositef1EditField.Visible = 'off';
            app.compositef1EditField.Tooltip = {'Frequency lower integration bound in kHz.'};
            app.compositef1EditField.Value = 1;
            app.compositef2EditFieldLabel = uilabel(app.ImagerenderingPanel);
            app.compositef2EditFieldLabel.Visible = 'off';
            app.compositef2EditFieldLabel.Text = 'f2';
            app.compositef2EditField = uieditfield(app.ImagerenderingPanel, 'numeric');
            app.compositef2EditField.Limits = [0 Inf];
            app.compositef2EditField.ValueChangedFcn = createCallbackFcn(app, @compositef2EditFieldValueChanged, true);
            app.compositef2EditField.Visible = 'off';
            app.compositef2EditField.Tooltip = {'Frequency upper integration bound in kHz.'};
            app.compositef2EditField.Value = 6;
            app.compositef3EditFieldLabel = uilabel(app.ImagerenderingPanel);
            app.compositef3EditFieldLabel.Visible = 'off';
            app.compositef3EditFieldLabel.Text = 'f3';
            app.compositef3EditField = uieditfield(app.ImagerenderingPanel, 'numeric');
            app.compositef3EditField.Limits = [0 Inf];
            app.compositef3EditField.ValueChangedFcn = createCallbackFcn(app, @compositef3EditFieldValueChanged, true);
            app.compositef3EditField.Visible = 'off';
            app.compositef3EditField.Tooltip = {'Frequency upper integration bound in kHz.'};
            app.compositef3EditField.Value = 30;
            app.ImageChoiceDropDown = uidropdown(app.ImagerenderingPanel);
            app.ImageChoiceDropDown.Items = {'Power doppler', 'Color image', '3D view'};
            app.ImageChoiceDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageChoiceDropDownValueChanged, true);
            app.ImageChoiceDropDown.Value = '3D view';
            app.gwLabel = uilabel(app.ImagerenderingPanel);
            app.gwLabel.Text = 'gw';
            app.gwEditField = uieditfield(app.ImagerenderingPanel, 'numeric');
            app.gwEditField.Limits = [0 Inf];
            app.gwEditField.ValueChangedFcn = createCallbackFcn(app, @gwEditFieldValueChanged, true);
            app.gwEditField.Tooltip = {'Magnitude of gaussian filter'};
            app.gwEditField.Value = 35;
            app.Panel_6 = uipanel(app.GridLayout2);
            app.NotesTextAreaLabel = uilabel(app.Panel_6);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.Panel_6);
            app.VolumeViewRenderingButton = uibutton(app.Panel_6, 'push');
            app.VolumeViewRenderingButton.ButtonPushedFcn = createCallbackFcn(app, @VolumeViewRendering, true);
            app.VolumeViewRenderingButton.Text = 'Volume View Rendering';
            app.Label_3 = uilabel(app.Panel_6);
            app.Label_3.Text = 'volume size';
            app.volumesizeEditField = uieditfield(app.Panel_6, 'numeric');
            app.volumesizeEditField.ValueChangedFcn = createCallbackFcn(app, @VolumeSizeValueChanged, true);
            app.depthpositionEditFieldLabel = uilabel(app.Panel_6);
            app.depthpositionEditFieldLabel.Text = 'depth position';
            app.depthpositionEditField = uieditfield(app.Panel_6, 'numeric');
            app.depthpositionEditField.Limits = [1 Inf];
            app.depthpositionEditField.ValueChangedFcn = createCallbackFcn(app, @DepthPosition, true);
            app.depthpositionEditField.Value = 1;
            app.depthrangeEditFieldLabel = uilabel(app.Panel_6);
            app.depthrangeEditFieldLabel.Text = 'depth range';
            app.depthrangeEditField = uieditfield(app.Panel_6, 'numeric');
            app.depthrangeEditField.Limits = [1 Inf];
            app.depthrangeEditField.ValueChangedFcn = createCallbackFcn(app, @DepthRange, true);
            app.depthrangeEditField.Value = 1;
            % Show the figure after all components are created
            app.LaserDopplerHolographyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = holo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LaserDopplerHolographyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LaserDopplerHolographyUIFigure)
        end
    end
end